classdef gisapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        HomeMenu             matlab.ui.container.Menu
        NewprojectMenu       matlab.ui.container.Menu
        OpenprojectMenu      matlab.ui.container.Menu
        SaveprojectMenu      matlab.ui.container.Menu
        AddprofileMenu       matlab.ui.container.Menu
        DeleteprofileMenu    matlab.ui.container.Menu
        MapMenu              matlab.ui.container.Menu
        LoadimageMenu        matlab.ui.container.Menu
        Menu_2               matlab.ui.container.Menu
        ProcessingMenu_3     matlab.ui.container.Menu
        TextArea             matlab.ui.control.TextArea
        TabGroup             matlab.ui.container.TabGroup
        LayersTab            matlab.ui.container.Tab
        RemoveButton         matlab.ui.control.Button
        AddButton            matlab.ui.control.Button
        ColorButton          matlab.ui.control.Button
        DownButton           matlab.ui.control.Button
        UpButton             matlab.ui.control.Button
        Tree                 matlab.ui.container.CheckBoxTree
        InfoPanel            matlab.ui.container.Panel
        Panel_2              matlab.ui.container.Panel
        CoordEditField       matlab.ui.control.EditField
        CoordEditFieldLabel  matlab.ui.control.Label
        ScaleEditField       matlab.ui.control.EditField
        ScaleEditFieldLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end


    properties (Access = private)
        processingapp
        % Map properties
        MapMinX
        MapMaxX
        MapMinY
        MapMaxY
        PlotElectrodes
        ShownPositions
        mapPathName='none'
        profilePath='none'
        profileName='none'
        profileColor='[1 1 0]'
        mapChildren
        PROFILES
        selectedNodes

    end

    methods (Access = public)

        function importdata_processingapp(app,Q,TAB)
            %% IMPORT DATA FROM THE PROCESSING APP (in round
            % brackets input from processing app)
            % Q contains the coordinates of the selected electrodes
            % Showpositions contains the plot of electrodes selected in
            % processingapp and overlaps the profile plot. Each time there
            % is a new selection the current Showpositions is deleted and a
            % new is created from Q


            ShownPositions=app.ShownPositions;
            if ~isempty(ShownPositions)
                delete (ShownPositions);
            end
            ShownPositions=gobjects(0);
            for r=1:height(Q)
                ShownPositions=[ShownPositions plot(app.UIAxes,TAB{4}(Q{r},3),TAB{4}(Q{r},4), 'o','MarkerFaceColor',[0.71765     0.27451           1],'MarkerEdgeColor',[1 1 1],'MarkerSize',4)];
            end
            app.ShownPositions=ShownPositions;
        end
    end

    methods (Access = private)

        function AddProfile(app)
            % LOAD PROFILE TO THE TREE AND THE PLOT
            fileID=fopen([app.profilePath app.profileName]);
            filescan=textscan(fileID,'%s','delimiter','\n','whitespace',' ');

            %% Add to PROFILES
            PROFILES=app.PROFILES;
            newrow=height(PROFILES)+1;
            PROFILES(newrow,1)=app.profileName;
            PROFILES(newrow,2)=app.profileColor;
            PROFILES(newrow,3)=app.profilePath;

            % sections from .qui file
            % TAB{1}-TAB{10}
            ROW=zeros(1,11);
            i=0;
            for i=1:11
                row_i=strfind(filescan{1}, append('%',num2str(i)));
                row_i=find(~cellfun('isempty',row_i),1);

                ROW(i)=row_i;
            end               % ROW (vector) contains the number of the row of each section

            j=0;
            for j=1:10
                TAB{j}=filescan{1}(ROW(j)+1:ROW(j+1)-1);
            end                % TAB (cell array) contains in each cell each section

            TAB{1,1}=str2num(char(TAB{1,1}));

            TAB{1,3}=str2num(char(TAB{1,3}));
            TAB{1,4}=str2num(char(TAB{1,4}));

            TAB{1,6}=str2num(char(TAB{1,6}));
            TAB{1,7}=str2num(char(TAB{1,7}));
            TAB{1,8}=str2num(char(TAB{1,8}));


            %% Create Node in Tree
            orderTree=app.Tree.Children;
            orderChecked=app.Tree.CheckedNodes;
            nodeProfile=uitreenode(app.Tree,'Text',app.profileName,'Tag',[app.profilePath app.profileName]);
            app.Tree.Children=[nodeProfile; orderTree];
            app.Tree.CheckedNodes=[nodeProfile; orderChecked];


            %% Plot Electrodes
            hold(app.UIAxes,'on')
            PlotElectrodes(height(app.Tree.CheckedNodes),1)=plot(app.UIAxes,TAB{4}(:,3),TAB{4}(:,4), 'o','MarkerFaceColor',app.profileColor,'MarkerEdgeColor',[0 0 0],'MarkerSize',4,'Tag',[app.profilePath app.profileName]);

            app.PlotElectrodes=PlotElectrodes;
            app.PROFILES=PROFILES;
        end

        function DeleteProfile(app)
            % DELETE PROFILE FROM THE TREE AND THE PLOT
            checkedNodes = app.Tree.CheckedNodes;
            selectedNodes = app.Tree.SelectedNodes;
            if ~isempty(app.Tree.SelectedNodes)
                if ismember(selectedNodes,checkedNodes)==1
                    for i=1:height(app.UIAxes.Children)
                        if strcmp(app.UIAxes.Children(i).Tag,app.Tree.SelectedNodes.Tag)==1
                            j=i;
                        end
                    end
                    delete(app.UIAxes.Children(j));
                end
                PROFILES=app.PROFILES;
                d=find(PROFILES(:,1)==app.Tree.SelectedNodes.Tag);
                PROFILES(d,:)=[];
                delete(app.Tree.SelectedNodes);
                app.PROFILES=PROFILES;
            end
        end

        function LoadMap(app)
            if  ~isempty(app.mapChildren)
                delete(app.mapChildren);
            end
            hold(app.UIAxes,'on')
            orderAxes=app.UIAxes.Children;

            map=imread(app.mapPathName);
            map=flipud(map);
            mapChildren=image(app.UIAxes,map,'XData',[app.MapMinX app.MapMaxX],'YData',[app.MapMinY app.MapMaxY],'Tag','map');
            app.UIAxes.XLim=[app.MapMinX app.MapMaxX];
            app.UIAxes.YLim=[app.MapMinY app.MapMaxY];
            set(app.UIAxes,'DataAspectRatio',[1 1 1])
            addAxes=app.UIAxes.Children(1);
            app.UIAxes.Children=[orderAxes; addAxes];
            app.mapChildren=mapChildren;
        end

        function ClearAll(app)
            %CLEAR ALL GIS WINDOW TO START A NEW PROJECT
            cla(app.UIAxes);
            delete(app.Tree.Children);

            app.mapPathName='none'
            app.profilePath='none'
            app.profileName='none'
            app.PROFILES=["FILENAME.QUI" "[R G B]" "PATHNAME"];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize the array where will be stored the information on
            % profiles (to save the project)

            PROFILES=["FILENAME.QUI" "[R G B]" "PATHNAME"];
            app.PROFILES=PROFILES;
        end

        % Menu selected function: LoadimageMenu
        function LoadimageMenuSelected(app, event)
            % ADD A BACKGROUND MAP TO THE PLOT FROM THE MENU:
            % ask for the image to use and the coordinates of the edges (UTM),
            % then it is loaded in the function LoadMap

            [mapName,mapPath]=uigetfile({'*.png;*.jpg;*.tif;*.tiff','background map'});
            addpath(mapPath);
            app.mapPathName=[mapPath mapName];

            x = inputdlg({'Min X','Max X','Min Y','Max Y'},...
                'Map Coordx', [1 12; 1 12; 1 12; 1 12]);
            app.MapMinX=str2double(x{1});
            app.MapMaxX=str2double(x{2});
            app.MapMinY=str2double(x{3});
            app.MapMaxY=str2double(x{4});

            app.LoadMap
        end

        % Menu selected function: AddprofileMenu
        function AddprofileMenuSelected(app, event)
            % ADD A PROFILE FROM THE MENU: ask for the qui file, then it
            % is loaded in the function AddProfile

            [filenameQui,pathnameQui]=uigetfile('*.qui*');
            addpath(pathnameQui);
            app.profilePath=pathnameQui;
            app.profileName=filenameQui;
            app.profileColor='[1 1 0]';

            app.AddProfile;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % ADD A PROFILE FROM THE BUTTON: ask for the qui file, then it
            % is loaded in the function AddProfile

            [filenameQui,pathnameQui]=uigetfile('*.qui*');
            addpath(pathnameQui);
            app.profilePath=pathnameQui;
            app.profileName=filenameQui;
            app.profileColor='[1 1 0]';

            app.AddProfile;
        end

        % Menu selected function: DeleteprofileMenu
        function DeleteprofileMenuSelected(app, event)
            app.DeleteProfile;
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            app.DeleteProfile;
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.selectedNodes = app.Tree.SelectedNodes;
        end

        % Menu selected function: ProcessingMenu_3
        function ProcessingMenu_3Selected(app, event)
            % ACCESS TO THE PROCESSING WINDOW OF THE SELECTED PROFILE
            % Open the qui file associated, scan it, create the variables to pass
            % to the processing widow:
            %   - TAB (cell array) contains in each cell each section from 1 to 10 of qui file
            %   - MPOL (array) contains the section of the multipoles (11th)
            %   - RAWDATA (array) rawdata for each multipole
            %   - DIST (array) for each electrode, number and its relative
            %   distance in the profile
            %   - QuiFile (string) path to the qui file


            if isempty(app.Tree.SelectedNodes)

                err=uiconfirm(app.UIFigure,'Please select a profile.', 'Processing request','Icon','warning','Options',{'OK'});

            else
                fileID=fopen(app.Tree.SelectedNodes.Tag);
                filescan=textscan(fileID,'%s','delimiter','\n','whitespace',' ');

                %% sections from .qui file
                %TAB{1}-TAB{10}
                ROW=zeros(1,11);
                i=0;
                for i=1:11
                    row_i=strfind(filescan{1}, append('%',num2str(i)));
                    row_i=find(~cellfun('isempty',row_i),1);

                    ROW(i)=row_i;
                end          % ROW (vector) contains the number of the row of each section

                j=0;
                for j=1:10
                    TAB{j}=filescan{1}(ROW(j)+1:ROW(j+1)-1);
                end          % TAB (cell array) contains in each cell each section

                TAB{1,1}=str2num(char(TAB{1,1}));

                TAB{1,3}=str2num(char(TAB{1,3}));
                TAB{1,4}=str2num(char(TAB{1,4}));

                TAB{1,6}=str2num(char(TAB{1,6}));
                TAB{1,7}=str2num(char(TAB{1,7}));
                TAB{1,8}=str2num(char(TAB{1,8}));


                %TAB{11}:MPOL
                rows_filescan=height(filescan{1});  %Computes number of rows in filescan

                cols_MPOL=0;
                rows_MPOL=0;
                for m=ROW(1,11)+1:rows_filescan
                    [A,COUNT,ERRMSG,NEXTINDEX] = sscanf(filescan{1}{m,1},'%e');
                    if COUNT>0
                        cols_MPOL=max(cols_MPOL,COUNT);
                        rows_MPOL=rows_MPOL+1;
                    end

                end     % Counts Col and Rows that will be in MPOL

                MPOL=zeros(rows_MPOL,cols_MPOL);

                k=0;
                for m=ROW(1,11)+1:rows_filescan
                    [A,COUNT,ERRMSG,NEXTINDEX] = sscanf(filescan{1}{m,1},'%e');
                    if COUNT>0
                        k=k+1;
                        MPOL(k,:)=A';
                    end

                    if isempty(ERRMSG)
                        RAWDATA{k,1}="";
                    else
                        RAWDATA{k,1}=filescan{1}{m,1}(NEXTINDEX:end);
                    end

                end                     % Create MPOL matrix and rawdata cell array

                DIST=zeros(height(TAB{4}),2);   % In DIST col 1 is the elNumber, col 2 is the distance
                DIST(1,1)=1;
                DIST(2,1)=2;
                DIST(1,2)=0;
                DIST(2,2)=sqrt((TAB{4}(2,3)-TAB{4}(1,3))^2+(TAB{4}(2,4)-TAB{4}(1,4))^2);

                for m=3:height(TAB{4})
                    DIST(m,2)=DIST(m-1,2)+sqrt((TAB{4}(m,3)-TAB{4}(m-1,3)).^2+(TAB{4}(m,4)-TAB{4}(m-1,4)).^2);
                    DIST(m,1)=TAB{4}(m,1);
                end


                % CALL PROCESSING APP (in parenthesis the variables to send
                % to it)

                QuiFile=app.Tree.SelectedNodes.Tag;
                app.processingapp=processingapp(app,TAB,MPOL,RAWDATA,DIST,QuiFile);
            end
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            % MOVES UP OF 1 POSITION THE SELECTED TAG IN THE PROFILES TREE
            % AND THE ASSOCIATED CHILDREN IN THE PLOT:
            % Store the current checked nodes in Tags:
            % Find the position of the selected children in the tree then
            % Order the children in the plot and in the tree
            % copying the children in variables and plotting in this order:
            %   - from the first children to the selected -2,
            %   - the selected children,
            %   - the children before the selected one,
            %   - from the children afer the selected one to the end.
            % Empty CheckedNodes and recreate with the new order using Tags

            if ~isempty(app.selectedNodes)
                hold(app.UIAxes,'on')
                checkedNodes = app.Tree.CheckedNodes;

                Tags=strings(height(checkedNodes),1);
                for t=1:height(checkedNodes)
                    Tags(t)=checkedNodes(t).Tag;
                end

                for n=1:height(app.Tree.Children)
                    if strcmp(app.Tree.Children(n).Tag,app.selectedNodes.Tag)==1
                        treerow=n;
                    end
                end

                if treerow>1
                    if ismember(app.selectedNodes,checkedNodes)==1
                        if ismember(app.Tree.Children(treerow-1),checkedNodes)==1
                            for n=1:height(checkedNodes)
                                if strcmp(checkedNodes(n).Tag,app.selectedNodes.Tag)==1
                                    checkedrow=n;
                                end
                            end
                            checked=checkedNodes(1:checkedrow);

                            if height(checked)>2
                                orderAxes1=app.UIAxes.Children(1:height(checked)-2);
                            end
                            orderAxes2=app.UIAxes.Children(height(checked));
                            orderAxes3=app.UIAxes.Children(height(checked)-1);
                            orderAxes4=app.UIAxes.Children(height(checked)+1:height(app.UIAxes.Children));
                            if height(checked)==2
                                app.UIAxes.Children=[orderAxes2; orderAxes3; orderAxes4];
                            else
                                app.UIAxes.Children=[orderAxes1; orderAxes2; orderAxes3; orderAxes4];
                            end
                        end
                    end


                    if treerow>2
                        orderTree1=app.Tree.Children(1:treerow-2);
                    end
                    orderTree2=app.Tree.Children(treerow);
                    orderTree3=app.Tree.Children(treerow-1);
                    orderTree4=app.Tree.Children(treerow+1:height(app.Tree.Children));

                    if treerow==2
                        app.Tree.Children=[orderTree2; orderTree3; orderTree4];
                    else
                        app.Tree.Children=[orderTree1; orderTree2; orderTree3; orderTree4];
                    end


                    app.Tree.CheckedNodes=[];

                    for c=1:height(app.Tree.Children)
                        if ismember(app.Tree.Children(c).Tag,Tags)==1
                            app.Tree.CheckedNodes=[app.Tree.CheckedNodes; app.Tree.Children(c)];
                        end
                    end
                end
            end


        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            % MOVES DOWN OF 1 POSITION THE SELECTED TAG IN THE PROFILES TREE
            % AND THE ASSOCIATED CHILDREN IN THE PLOT:
            % Store the current checked nodes in Tags:
            % Find the position of the selected children in the tree then
            % Order the children in the plot and in the tree
            % copying the children in variables and plotting in this order:
            %   - from the first children to the children before the selected one,
            %   - the children after the selected one,
            %   - the selected children,
            %   - from the selected children +2 to the end.
            % Empty CheckedNodes and recreate with the new order using Tags

            if ~isempty(app.selectedNodes)
                hold(app.UIAxes,'on')
                checkedNodes = app.Tree.CheckedNodes;

                Tags=strings(height(checkedNodes),1);
                numTreeChil=height(app.Tree.Children);

                for t=1:height(checkedNodes)
                    Tags(t)=checkedNodes(t).Tag;
                end

                for n=1:height(app.Tree.Children)
                    if strcmp(app.Tree.Children(n).Tag,app.selectedNodes.Tag)==1
                        treerow=n;
                    end
                end

                if treerow<numTreeChil
                    if ismember(app.selectedNodes,checkedNodes)==1
                        if ismember(app.Tree.Children(treerow+1),checkedNodes)==1
                            for n=1:height(checkedNodes)
                                if strcmp(checkedNodes(n).Tag,app.selectedNodes.Tag)==1
                                    checkedrow=n;
                                end
                            end
                            checked=checkedNodes(1:checkedrow);

                            orderAxes1=app.UIAxes.Children(1:height(checked)-1);
                            orderAxes2=app.UIAxes.Children(height(checked)+1);
                            orderAxes3=app.UIAxes.Children(height(checked));
                            orderAxes4=app.UIAxes.Children(height(checked)+2:height(app.UIAxes.Children));
                            app.UIAxes.Children=[orderAxes1; orderAxes2; orderAxes3; orderAxes4];
                        end
                    end


                    orderTree1=app.Tree.Children(1:treerow-1);
                    orderTree2=app.Tree.Children(treerow+1);
                    orderTree3=app.Tree.Children(treerow);
                    if treerow<numTreeChil-1
                        orderTree4=app.Tree.Children(treerow+1:height(app.Tree.Children));
                    end

                    if treerow<numTreeChil-1
                        app.Tree.Children=[orderTree1; orderTree2; orderTree3; orderTree4];
                    else
                        app.Tree.Children=[orderTree1; orderTree2; orderTree3];
                    end


                    app.Tree.CheckedNodes=[];

                    for c=1:height(app.Tree.Children)
                        if ismember(app.Tree.Children(c).Tag,Tags)==1
                            app.Tree.CheckedNodes=[app.Tree.CheckedNodes; app.Tree.Children(c)];
                        end
                    end
                end
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            % SHOW OR HIDE PROFILE IN THE PLOT BASED ON THE CHECKED NODES

            hold(app.UIAxes,'on')
            checkedNodes = app.Tree.CheckedNodes;
            selectedNodes = app.Tree.SelectedNodes;
            PROFILES=app.PROFILES;

            PlotElectrodes=app.PlotElectrodes;
            if ismember(selectedNodes,checkedNodes)==0
                for n=1:height(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(n).Tag,selectedNodes.Tag)==1
                        axesrow=n;
                    end
                end
                delete(app.UIAxes.Children(axesrow));

            else
                fileID=fopen(selectedNodes.Tag);
                filescan=textscan(fileID,'%s','delimiter','\n','whitespace',' ');

                % sections from .qui file
                % TAB{1}-TAB{10}
                ROW=zeros(1,11);
                i=0;
                for i=1:11
                    row_i=strfind(filescan{1}, append('%',num2str(i)));
                    row_i=find(~cellfun('isempty',row_i),1);

                    ROW(i)=row_i;
                end               % ROW (vector) contains the number of the row of each section

                j=0;
                for j=1:10
                    TAB{j}=filescan{1}(ROW(j)+1:ROW(j+1)-1);
                end                % TAB (cell array) contains in each cell each section

                TAB{1,4}=str2num(char(TAB{1,4}));
                for n=1:height(app.Tree.Children)
                    if strcmp(app.Tree.Children(n).Tag,selectedNodes.Tag)==1
                        treerow=n;
                    end
                end

                for n=1:height(checkedNodes)
                    if strcmp(checkedNodes(n).Tag,selectedNodes.Tag)==1
                        checkedrow=n;
                    end
                end
                checked=checkedNodes(1:checkedrow);
                if checkedrow>1
                    orderAxes1=app.UIAxes.Children(1:height(checked)-1);
                end
                orderAxes3=app.UIAxes.Children(height(checked):height(app.UIAxes.Children));

                d=find(PROFILES(:,1)==app.Tree.SelectedNodes.Tag);
                Color=PROFILES(d,2);

                PlotElectrodes(treerow,1)=plot(app.UIAxes,TAB{4}(:,3),TAB{4}(:,4), 'o','MarkerFaceColor',Color,'MarkerEdgeColor',[0 0 0],'MarkerSize',4,'Tag',selectedNodes.Tag);

                orderAxes2=app.UIAxes.Children(1);
                if checkedrow>1
                    app.UIAxes.Children=[orderAxes1; orderAxes2; orderAxes3];
                else
                    app.UIAxes.Children=[orderAxes2; orderAxes3];
                end
            end

            app.PlotElectrodes=PlotElectrodes;
            app.PROFILES=PROFILES;

        end

        % Button pushed function: ColorButton
        function ColorButtonPushed(app, event)
            % CHANGE THE COLOR OF THE POINTS OF THE SELECTED PROFILE IN THE PLOT

            hold(app.UIAxes,'on')
            checkedNodes = app.Tree.CheckedNodes;
            selectedNodes = app.Tree.SelectedNodes;
            PROFILES=app.PROFILES;

            if ~isempty(app.Tree.SelectedNodes)
                PlotElectrodes=app.PlotElectrodes;
                Color = uisetcolor([1 1 0]);

                d=find(PROFILES(:,1)==app.Tree.SelectedNodes.Text);
                PROFILES(d,2)=append("[",num2str(Color),"]");

                for n=1:height(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(n).Tag,selectedNodes.Tag)==1
                        axesrow=n;
                    end
                end

                orderAxes1=app.UIAxes.Children(1:axesrow-1);
                orderAxes3=app.UIAxes.Children(axesrow+1:height(app.UIAxes.Children));
                delete(app.UIAxes.Children(axesrow));

                fileID=fopen(app.Tree.SelectedNodes.Tag);
                filescan=textscan(fileID,'%s','delimiter','\n','whitespace',' ');

                % sections from .qui file
                % TAB{1}-TAB{10}
                ROW=zeros(1,11);
                i=0;
                for i=1:11
                    row_i=strfind(filescan{1}, append('%',num2str(i)));
                    row_i=find(~cellfun('isempty',row_i),1);

                    ROW(i)=row_i;
                end               % ROW (vector) contains the number of the row of each section

                j=0;
                for j=1:10
                    TAB{j}=filescan{1}(ROW(j)+1:ROW(j+1)-1);
                end                % TAB (cell array) contains in each cell each section

                TAB{1,4}=str2num(char(TAB{1,4}));

                for n=1:height(app.Tree.Children)
                    if strcmp(app.Tree.Children(n).Tag,selectedNodes.Tag)==1
                        treerow=n;
                    end
                end
                PlotElectrodes(treerow,1)=plot(app.UIAxes,TAB{4}(:,3),TAB{4}(:,4), 'o','MarkerFaceColor',Color,'MarkerEdgeColor',[0 0 0],'MarkerSize',4,'Tag',selectedNodes.Tag);

                orderAxes2=app.UIAxes.Children(1);
                app.UIAxes.Children=[orderAxes1; orderAxes2; orderAxes3];
            end
            app.PlotElectrodes=PlotElectrodes;
            app.PROFILES=PROFILES;
        end

        % Menu selected function: SaveprojectMenu
        function SaveprojectMenuSelected(app, event)
            % SAVE PROJECT: create a text file in which are stored the
            % profiles in the tree saving (name, color, path) in loading
            % order and the map and the coordinates (UTM) of the edges


            PROFILES=app.PROFILES;
            sortedPROFILES=cell(height(app.Tree.Children),1);

            [projectName,projectPath]=uiputfile('*.txt', 'Save project as');
            addpath(projectPath);
            FID=fopen(append(projectPath,projectName),'w+');

            fprintf(FID,'%s\n','%A Profiles');
            PATHS=strings(height(PROFILES)-1,1);
            for d=1:height(PROFILES)
                PATHS(d,1)=append(PROFILES(d,3),PROFILES(d,1));
            end

            for i=height(app.Tree.Children):-1:1
                d=find(PATHS(:,1)==app.Tree.Children(i).Tag);

                fprintf(FID,'%s\n',PROFILES(d,1));
                fprintf(FID,'%s\n',PROFILES(d,2));
                fprintf(FID,'%s\n',PROFILES(d,3));
                fprintf(FID,'\n');
            end

            fprintf(FID,'%s\n','%B Map');
            fprintf(FID,'%s\n',app.mapPathName);
            fprintf(FID,'%12.4f\n',app.MapMinX);
            fprintf(FID,'%12.4f\n',app.MapMaxX);
            fprintf(FID,'%12.4f\n',app.MapMinY);
            fprintf(FID,'%12.4f\n',app.MapMaxY);
            fprintf(FID,'\n');

            fclose(FID);
            
            saved=uiconfirm(app.UIFigure,'Saved', 'Saving project','Icon','success','Options',{'OK'});

        end

        % Menu selected function: OpenprojectMenu
        function OpenprojectMenuSelected(app, event)
            % LOAD PROFILES AND MAP FROM AN EXISTING PROJECT

            if ~isempty(app.Tree.Children)
                YN = uiconfirm(app.UIFigure,'Do you want to save the current project?', 'Open project request');
                if strcmpi(YN,'OK')
                    app.SaveprojectMenuSelected;
                end
            end
            app.ClearAll;


            [projectName,projectPath]=uigetfile('*.txt', 'Open project');
            addpath(projectPath);
            fileID=fopen([projectPath projectName]);
            filescan=textscan(fileID,'%s','delimiter','\n','whitespace',' ');


            %% Load Profiles
            rowB=strfind(filescan{1}, '%B Map');
            rowB=find(~cellfun('isempty',rowB),1);

            n=(rowB-2)/4;

            for i=1:n
                rowA=1+4*(i-1);
                app.profileName=filescan{1}{rowA+1};
                app.profileColor=filescan{1}{rowA+2};
                app.profilePath=filescan{1}{rowA+3};
                addpath(app.profilePath);
                app.AddProfile;
            end

            %% Load Map
            app.mapPathName=filescan{1}{rowB+1};
            if app.mapPathName~='none'
                [mapPath,mapName,mapExt] = fileparts(app.mapPathName);
                addpath(mapPath);
                app.MapMinX=str2num(filescan{1}{rowB+2});
                app.MapMaxX=str2num(filescan{1}{rowB+3});
                app.MapMinY=str2num(filescan{1}{rowB+4});
                app.MapMaxY=str2num(filescan{1}{rowB+5});
                app.LoadMap;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK')
                delete(app.processingapp)
                delete(app)
                
            end
        end

        % Menu selected function: NewprojectMenu
        function NewprojectMenuSelected(app, event)
            if ~isempty(app.Tree.Children)
                YN = uiconfirm(app.UIFigure,'Do you want to save the current project?', 'Open project request');
                if strcmpi(YN,'Save')
                    app.SaveprojectMenuSelected;
                end
            end
            app.ClearAll;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HomeMenu = uimenu(app.UIFigure);
            app.HomeMenu.Text = 'Home';
            app.NewprojectMenu = uimenu(app.HomeMenu);
            app.NewprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprojectMenuSelected, true);
            app.NewprojectMenu.Text = 'New project';
            app.OpenprojectMenu = uimenu(app.HomeMenu);
            app.OpenprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenprojectMenuSelected, true);
            app.OpenprojectMenu.Text = 'Open project';
            app.SaveprojectMenu = uimenu(app.HomeMenu);
            app.SaveprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveprojectMenuSelected, true);
            app.SaveprojectMenu.Text = 'Save project';
            app.AddprofileMenu = uimenu(app.HomeMenu);
            app.AddprofileMenu.MenuSelectedFcn = createCallbackFcn(app, @AddprofileMenuSelected, true);
            app.AddprofileMenu.Text = 'Add profile ';
            app.DeleteprofileMenu = uimenu(app.HomeMenu);
            app.DeleteprofileMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteprofileMenuSelected, true);
            app.DeleteprofileMenu.Text = 'Delete profile';
            app.MapMenu = uimenu(app.UIFigure);
            app.MapMenu.Text = 'Map';
            app.LoadimageMenu = uimenu(app.MapMenu);
            app.LoadimageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadimageMenuSelected, true);
            app.LoadimageMenu.Text = 'Load image';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = 'Menu';
            app.ProcessingMenu_3 = uimenu(app.Menu_2);
            app.ProcessingMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ProcessingMenu_3Selected, true);
            app.ProcessingMenu_3.Text = 'Processing';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X UTM')
            ylabel(app.UIAxes, 'Y UTM')
            app.Panel_2 = uipanel(app.UIFigure);
            app.ScaleEditFieldLabel = uilabel(app.Panel_2);
            app.ScaleEditFieldLabel.Visible = 'off';
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.Panel_2, 'text');
            app.ScaleEditField.Visible = 'off';
            app.CoordEditFieldLabel = uilabel(app.Panel_2);
            app.CoordEditFieldLabel.Visible = 'off';
            app.CoordEditFieldLabel.Text = 'Coord';
            app.CoordEditField = uieditfield(app.Panel_2, 'text');
            app.CoordEditField.Visible = 'off';
            app.InfoPanel = uipanel(app.UIFigure);
            app.InfoPanel.Title = 'Info';
            app.InfoPanel.Visible = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LayersTab = uitab(app.TabGroup);
            app.LayersTab.Title = 'Layers';
            app.Tree = uitree(app.LayersTab, 'checkbox');
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.UpButton = uibutton(app.LayersTab, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Icon = 'Up.png';
            app.UpButton.Text = '';
            app.DownButton = uibutton(app.LayersTab, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Icon = 'Down.png';
            app.DownButton.Text = '';
            app.ColorButton = uibutton(app.LayersTab, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Icon = 'ChangeColor.png';
            app.ColorButton.Text = '';
            app.AddButton = uibutton(app.LayersTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Icon = 'AddProfile.png';
            app.AddButton.Text = '';
            app.RemoveButton = uibutton(app.LayersTab, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Icon = 'RemoveProfile.png';
            app.RemoveButton.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gisapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end