classdef processingapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HideAllButton                   matlab.ui.control.Button
        ShowAllButton                   matlab.ui.control.Button
        SaveprocessingButton            matlab.ui.control.Button
        HiderejectedpointsCheckBox      matlab.ui.control.CheckBox
        TabGroup2                       matlab.ui.container.TabGroup
        DataprofileandPseudosectionTab  matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        SettingsButton_pseudosec        matlab.ui.control.Button
        SettingsButton_profile          matlab.ui.control.Button
        ColorbarEditorButton            matlab.ui.control.Button
        CurveshiftfactorSpinner         matlab.ui.control.Spinner
        CurveshiftfactorSpinnerLabel    matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        DatastripeTab                   matlab.ui.container.Tab
        Panel_5                         matlab.ui.container.Panel
        UIAxes3_2                       matlab.ui.control.UIAxes
        Panel_4                         matlab.ui.container.Panel
        SettingsButton_stripe           matlab.ui.control.Button
        Next                            matlab.ui.control.Button
        Previous                        matlab.ui.control.Button
        DecaysPanel                     matlab.ui.container.Panel
        RawdataButton                   matlab.ui.control.Button
        SettingsButton_decay            matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        PositionPanel                   matlab.ui.container.Panel
        SettingsButton_positions        matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        ProfileTab                      matlab.ui.container.Tab
        Tree                            matlab.ui.container.CheckBoxTree
    end


    properties (Access = private)
        % to call other apps
        callingapp %gisapp
        settings_profileapp
        settings_pseudosecapp
        settings_stripeapp
        settings_decayapp
        settings_positionsapp

        % Input from gisapp
        TAB         % Tables 1-10
        MPOL        % Table 11
        RAWDATA     % Rawdata name from table 11
        DIST        % Distance between electrodes
        QuiFile     % Name of .qui file

        NGates
        NameSetPdepthSide
        SetPdepthSide
        SetPdepth
        nodeDataProfile
        FLAGS
        MPOLRhoaShift
        ROWSChecked

        plotDataProfile
        plotSelectedProfile

        rowFLAGSxDecays
        rowFLAGSxRhoa
        rowFLAGS
        colFLAGS

        ShownPositions
        ShownProfile
        ShownPseudosec

        settingsProfile
        settingsPseudosec
        settingsStripe
        settingsDecay
        settingsPositions

        IntStripe

        MSet
        Xfocus
        Std

        kMid
        kEnd
    end

    methods (Access = public)

        % FUNCTIONS TO IMPORT DATA FROM SETTINGS APP

        function importdata_settings_profile(app,settingsProfile)
            app.UIAxes2.XLim=settingsProfile{2}(1,:);
            app.UIAxes2.YLim=settingsProfile{2}(2,:);
            app.settingsProfile=settingsProfile;
        end

        function importdata_settings_pseudosec(app,settingsPseudosec)
            app.UIAxes3.XLim=settingsPseudosec{2}(1,:);
            app.UIAxes3.YLim=settingsPseudosec{2}(2,:);

            %             colorscaleLim=settingsPseudosec{4}(2,:);
            %             colormap (app.UIAxes3,settingsPseudosec{3}(2,:));
            %             caxis(app.UIAxes3,colorscaleLim);

            app.settingsPseudosec=settingsPseudosec;
        end

        function importdata_settings_stripe(app,settingsStripe)
            app.UIAxes3_2.XLim=settingsStripe{2}(1,:);
            app.UIAxes3_2.YLim=settingsStripe{2}(2,:);

            IntStripe(1)=settingsStripe{4};
            IntStripe(2)=settingsStripe{2}(1,1);
            IntStripe(3)=settingsStripe{2}(1,2);

            app.settingsStripe=settingsStripe;
            IntStripe=app.IntStripe;
        end

        function importdata_settings_decay(app,settingsDecay)
            app.UIAxes4.XLim=settingsDecay{2}(1,:);
            app.UIAxes4.YLim=settingsDecay{2}(2,:);
            app.settingsDecay=settingsDecay;
        end

        function importdata_settings_positions(app,settingsPositions)
            app.UIAxes.XLim=settingsPositions{2}(1,:);
            app.UIAxes.YLim=settingsPositions{2}(2,:);
            app.settingsPositions=settingsPositions;
        end
    end

    methods (Access = private)

        function EnableKeyboardShortCuts(app)
            %% get the brush handle
            h=brush(app.UIFigure);
            %enable brush on all UIFigure axes; this resets the keypress
            h.Enable='on';
            %% allow to change listeners, so we can re-assign keyboard
            hManager = uigetmodemanager(app.UIFigure);
            [hManager.WindowListenerHandles.Enabled] = deal(false);
            %% re-assign keyboard callback
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
        end


        %%%%%% PLOTS FUNCTIONS %%%%%%%%

        function PlotPosition(app)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.DIST(:,2),app.TAB{4}(:,5), 'k.');
        end

        function PlotDataProfile(app)
            cla(app.UIAxes2)

            kMid=app.kMid;
            FLAGS=app.FLAGS;
            checkedNodes = app.Tree.CheckedNodes;
            SetPdepthSide=app.SetPdepthSide;
            plotDataProfile=app.plotDataProfile;
            NameSetPdepthSide=app.NameSetPdepthSide;
            CShiftvalue = app.CurveshiftfactorSpinner.Value;
            TextcheckedNodes=strings(height(checkedNodes),1);

            for i=1:height(checkedNodes)
                TextcheckedNodes(i)=checkedNodes(i).Text;
            end

            MPOLRhoaShift=zeros(height(app.MPOL),1);
            for m=1:height(app.SetPdepthSide)
                for n=1:3

                    if ~isempty(app.SetPdepthSide{m,n})

                        index=height(SetPdepthSide)-(m-1);
                        RhoaShift=zeros(height(SetPdepthSide{m,n}),1);

                        for p=1:height(SetPdepthSide{m,n})
                            RhoaShift(p,1)=SetPdepthSide{m,n}(p,4).*CShiftvalue^index;
                            MPOLRhoaShift(SetPdepthSide{m,n}(p,1),1)=RhoaShift(p,1);
                        end

                        if ismember(NameSetPdepthSide{m,n},TextcheckedNodes)==1
                            if app.HiderejectedpointsCheckBox.Value==0
                                d=find(FLAGS(SetPdepthSide{m,n}(:,1),1,kMid)==1);
                                if ~isempty(d)
                                    plot(app.UIAxes2,SetPdepthSide{m,n}(:,2),abs(RhoaShift(:,1)),'-','Color',[0.7 0.7 0.7]);
                                    plot(app.UIAxes2,SetPdepthSide{m,n}(d,2),abs(RhoaShift(d,1)),'.','Color',[0.7 0.7 0.7],'Linestyle',"none");
                                end
                            end
                            d=find(FLAGS(SetPdepthSide{m,n}(:,1),1,kMid)==0);
                            d=d';
                            Dd=diff([0,diff(d)==1,0]);
                            firstd=d(Dd>0);
                            lastd=d(Dd<0);

                            for e=1:length(firstd)
                                plot(app.UIAxes2,SetPdepthSide{m,n}(firstd(e):lastd(e),2),abs(RhoaShift(firstd(e):lastd(e),1)),'-','Color','#1F1F1F');
                            end
                            plot(app.UIAxes2,SetPdepthSide{m,n}(d,2),abs(RhoaShift(d,1)),'.','Color','#1F1F1F','LineStyle',"none");

                        end

                    end
                end
            end

            app.plotDataProfile=plotDataProfile;
            app.MPOLRhoaShift=MPOLRhoaShift;
        end


        function PlotPseudosection(app)
            cla(app.UIAxes3)
            kMid=app.kMid;
            SetPdepthSide=app.SetPdepthSide;
            FLAGS=app.FLAGS;

            for m=1:height(SetPdepthSide)
                for n=1:3
                    if ~isempty(app.SetPdepthSide{m,n})
                        if app.HiderejectedpointsCheckBox.Value==0
                            d=find(FLAGS(SetPdepthSide{m,n}(:,1),1,kMid)==1);
                            scatter(app.UIAxes3,SetPdepthSide{m,n}(d,2),SetPdepthSide{m,n}(d,3),70,[0.7 0.7 0.7],'filled','s','Tag',app.NameSetPdepthSide{m,n});
                        end
                        d=find(FLAGS(SetPdepthSide{m,n}(:,1),1,kMid)==0);
                        color=abs(SetPdepthSide{m,n}(d,4));
                        scatter(app.UIAxes3,SetPdepthSide{m,n}(d,2),SetPdepthSide{m,n}(d,3),70,color,'filled','s','Tag',app.NameSetPdepthSide{m,n});
                    end
                end
            end
        end


        function PlotDataStripe(app)
            cla(app.UIAxes3_2)

            selectedNodes = app.Tree.SelectedNodes;
            SetPdepthSide=app.SetPdepthSide;
            NGates=app.NGates;
            MPOL=app.MPOL;
            FLAGS=app.FLAGS;
            MSet=app.MSet;
            Xfocus=app.Xfocus;
            Std=app.Std;
            IntStripe=app.IntStripe;

            [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);

            %             rowInt=find(SetPdepthSide{m,n}(:,2)>=IntStripe(2) & SetPdepthSide{m,n}(:,2)<=IntStripe(3));
            hSet=height(SetPdepthSide{m,n});

            if isempty(MSet{1})
                for g=1:NGates
                    for h=1:hSet
                        MSet{g}(h)=MPOL(SetPdepthSide{m,n}(h,1),19+NGates*2+g);
                        Xfocus{g}(h)=SetPdepthSide{m,n}(h,2);
                        Std{g}(h)=MPOL(SetPdepthSide{m,n}(h,1),16+g);
                    end
                end
            end


            for g=2:NGates

                for h=1:hSet
                    Flags{g}(h)=FLAGS(SetPdepthSide{m,n}(h,1),g,10);
                end

                gg=find(Flags{g}==1);
                gb=find(Flags{g}==0 & MSet{g}>0);
                gr=find(Flags{g}==0 & MSet{g}<=0);

                if app.HiderejectedpointsCheckBox.Value==0
                    %% Grey
                    plotStripe(g,1)=plot(app.UIAxes3_2,Xfocus{g},abs(MSet{g}),'-','LineWidth',0.5,'Color',[0.7 0.7 0.7]);

                    if ~isempty(gg)
                        plotStripe(g,2)=errorbar(app.UIAxes3_2,Xfocus{g}(gg),abs(MSet{g}(gg)),abs(Std{g}(gg)),'.','Color',[0.7 0.7 0.7],'LineStyle','none');
                        plotStripe(g,3)=plot(app.UIAxes3_2,Xfocus{g}(gg),abs(MSet{g}(gg)),'MarkerFaceColor',[0.7 0.7 0.7],'MarkerEdgeColor',[0.7 0.7 0.7],'Marker','o','MarkerSize',2,'LineStyle','none','Tag',num2str(g));
                    end
                end

                %                     Dgr=diff([0,diff(gr)==1,0]);
                %                     firstgr=gr(Dgr>0);
                %                     lastgr=gr(Dgr<0);

                gbr=sort([gb gr]);
                Dgbr=diff([0,diff(gbr)==1,0]);
                firstgbr=gbr(Dgbr>0);
                lastgbr=gbr(Dgbr<0);

                Dgb=diff([0,diff(gb)==1,0]);
                firstgb=gb(Dgb>0);
                lastgb=gb(Dgb<0);

                %% Red
                for e=1:length(firstgbr)
                    plotStripe(g,4)=plot(app.UIAxes3_2,Xfocus{g}(firstgbr(e):lastgbr(e)),abs(MSet{g}(firstgbr(e):lastgbr(e))),'-','LineWidth',0.5,'Color',[1 0 0]);
                end
                if ~isempty(gr)
                    plotStripe(g,5)=errorbar(app.UIAxes3_2,Xfocus{g}(gr),abs(MSet{g}(gr)),abs(Std{g}(gr)),'.','LineStyle','none','Color',[1 0 0]);
                    plotStripe(g,6)=plot(app.UIAxes3_2,Xfocus{g}(gr),abs(MSet{g}(gr)),'o','LineStyle','none','MarkerFaceColor',[1 0 0],'MarkerEdgeColor',[1 0 0],'MarkerSize',2,'Tag',num2str(g));
                end

                %% Black
                for e=1:length(firstgb)
                    plotStripe(g,7)=plot(app.UIAxes3_2,Xfocus{g}(firstgb(e):lastgb(e)),abs(MSet{g}(firstgb(e):lastgb(e))),'-','LineWidth',0.5,'Color','#1F1F1F');
                end
                if ~isempty(gb)
                    plotStripe(g,8)=errorbar(app.UIAxes3_2,Xfocus{g}(gb),abs(MSet{g}(gb)),abs(Std{g}(gb)),'.','LineStyle','none','Color','#1F1F1F');
                    plotStripe(g,9)=plot(app.UIAxes3_2,Xfocus{g}(gb),abs(MSet{g}(gb)),'o','LineStyle','none','MarkerFaceColor','#1F1F1F','MarkerEdgeColor','#1F1F1F','MarkerSize',2,'Tag',num2str(g));
                end

            end


            app.MSet=MSet;
            app.Xfocus=Xfocus;
            app.Std=Std;
        end


        function PlotDecay(app)
            cla(app.UIAxes4)

            MPOL=app.MPOL;
            TAB=app.TAB;
            FLAGS=app.FLAGS;
            rowFLAGSxDecays=app.rowFLAGSxDecays;
            NGates=app.NGates;

            for j=1:height(rowFLAGSxDecays)

                eg=find(FLAGS(rowFLAGSxDecays(j),2:NGates,10)==1);
                eb=find(FLAGS(rowFLAGSxDecays(j),2:NGates,10)==0 & MPOL(rowFLAGSxDecays(j),21+NGates*2:19+NGates*3)>0);
                er=find(FLAGS(rowFLAGSxDecays(j),2:NGates,10)==0 & MPOL(rowFLAGSxDecays(j),21+NGates*2:19+NGates*3)<=0);

                eg=eg+1;
                eb=eb+1;
                er=er+1;

                if app.HiderejectedpointsCheckBox.Value==0
                    %% Grey
                    plotDecay(rowFLAGSxDecays(j),1)=plot(app.UIAxes4,abs(TAB{7}(2:NGates,4)),abs(MPOL(rowFLAGSxDecays(j),21+NGates*2:19+NGates*3)),'-','LineWidth',0.5,'Color',[0.7 0.7 0.7]);

                    if ~isempty(eg)
                        plotDecay(rowFLAGSxDecays(j),2)=errorbar(app.UIAxes4,abs(TAB{7}(eg,4)),abs(MPOL(rowFLAGSxDecays(j),19+NGates*2+eg)),abs(MPOL(rowFLAGSxDecays(j),16+eg)),'.','Color',[0.7 0.7 0.7],'LineStyle','none');
                        plotDecay(rowFLAGSxDecays(j),3)=plot(app.UIAxes4,abs(TAB{7}(eg,4)),abs(MPOL(rowFLAGSxDecays(j),19+NGates*2+eg)),'MarkerFaceColor',[0.7 0.7 0.7],'MarkerEdgeColor',[0.7 0.7 0.7],'Marker','o','MarkerSize',2,'LineStyle','none','Tag',num2str(rowFLAGSxDecays(j)));

                    end
                end

                Deb=diff([0,diff(eb)==1,0]);
                firsteb=eb(Deb>0);
                lasteb=eb(Deb<0);

                ebr=sort([eb er]);
                Debr=diff([0,diff(ebr)==1,0]);
                firstebr=ebr(Debr>0);
                lastebr=ebr(Debr<0);

                Der=diff([0,diff(er)==1,0]);
                firster=er(Der>0);
                laster=er(Der<0);

                %% Red
                for e=1:length(firstebr)
                    plotDecay(rowFLAGSxDecays(j),4)=plot(app.UIAxes4,abs(TAB{7}(firstebr(e):lastebr(e),4)),abs(MPOL(rowFLAGSxDecays(j),19+NGates*2+firstebr(e):19+NGates*2+lastebr(e))),'-','LineWidth',0.5,'Color',[1 0 0]);
                end
                if ~isempty(er)
                    plotDecay(rowFLAGSxDecays(j),5)=errorbar(app.UIAxes4,abs(TAB{7}(er,4)),abs(MPOL(rowFLAGSxDecays(j),19+NGates*2+er)),abs(MPOL(rowFLAGSxDecays(j),16+er)),'.','Color',[1 0 0],'LineStyle','none');
                    plotDecay(rowFLAGSxDecays(j),6)=plot(app.UIAxes4,abs(TAB{7}(er,4)),abs(MPOL(rowFLAGSxDecays(j),19+NGates*2+er)),'o','MarkerFaceColor',[1 0 0],'MarkerEdgeColor',[1 0 0],'MarkerSize',2,'LineStyle','none','Tag',num2str(rowFLAGSxDecays(j)));
                end


                %% Black
                for e=1:length(firsteb)
                    plotDecay(rowFLAGSxDecays(j),7)=plot(app.UIAxes4,abs(TAB{7}(firsteb(e):lasteb(e),4)),abs(MPOL(rowFLAGSxDecays(j),19+NGates*2+firsteb(e):19+NGates*2+lasteb(e))),'-','LineWidth',0.5,'Color','#1F1F1F');
                end
                if ~isempty(eb)
                    plotDecay(rowFLAGSxDecays(j),8)=errorbar(app.UIAxes4,abs(TAB{7}(eb,4)),abs(MPOL(rowFLAGSxDecays(j),19+NGates*2+eb)),abs(MPOL(rowFLAGSxDecays(j),16+eb)),'.','Color','#1F1F1F','LineStyle','none');
                    plotDecay(rowFLAGSxDecays(j),9)=plot(app.UIAxes4,abs(TAB{7}(eb,4)),abs(MPOL(rowFLAGSxDecays(j),19+NGates*2+eb)),'o','MarkerFaceColor','#1F1F1F','MarkerEdgeColor','#1F1F1F','MarkerSize',2,'LineStyle','none','Tag',num2str(rowFLAGSxDecays(j)));
                end

            end
        end


        %%%%%%  FUNCTIONS TO IDENTIFY SELECTED MEASUREMENTS %%%%%%%

        % IDENTIFY THE QUADRUPOLE FROM THE SELECTED POINTS (row in qui file) TO PLOT THE DECAYS:
        % first search in data profiles, if there aren't selected points search in
        % Pseudosection, then in Positions and finally in Data Stripe,
        % until a selection is found
        % OUTPUT: rowFLAGSxDecays = array with the number of the rows in MPOL of
        % the multipoles selected

        function FindSelectedRow(app)
            %% Find row in MPOL
            MPOL=app.MPOL;
            MPOLRhoaShift=app.MPOLRhoaShift;
            NGates=app.NGates;

            rowFLAGSxDecays=int16.empty;

            %% Find row of brushed points in Data Profiles (if present)
            for l=1:length(app.UIAxes2.Children)
                d=find(app.UIAxes2.Children(l).BrushData==1);
                if ~isempty(d)
                    for i=1:length(d)
                        XData=app.UIAxes2.Children(l).XData(d(i));
                        YData=app.UIAxes2.Children(l).YData(d(i));
                        row=find(MPOL(:,10)==XData & abs(MPOLRhoaShift(:,1))==YData);
                        rowFLAGSxDecays=[rowFLAGSxDecays; row];
                        rowFLAGSxDecays=unique(rowFLAGSxDecays);
                    end
                end
            end

            if isempty(rowFLAGSxDecays)
                %% Find row of brushed points in Pseudosection (if present)
                for l=1:length(app.UIAxes3.Children)
                    d=find(app.UIAxes3.Children(l).BrushData==1);
                    if ~isempty(d)
                        for i=1:length(d)
                            XData=app.UIAxes3.Children(l).XData(d(i));
                            YData=app.UIAxes3.Children(l).YData(d(i));
                            row=find(MPOL(:,10)==XData & MPOL(:,12)==YData);
                            rowFLAGSxDecays=[rowFLAGSxDecays; row];
                            rowFLAGSxDecays=unique(rowFLAGSxDecays);
                        end
                    end
                end
            end

            if isempty(rowFLAGSxDecays)
                %% Find row of brushed points in Positions (if present)
                for l=1:length(app.UIAxes.Children)
                    d=find(app.UIAxes.Children(1).BrushData==1);
                    if ~isempty(d)
                        for i=1:length(d)
                            rowA=find(MPOL(:,4)==d(i));       %%%%%% consider all the electrodes ABMN
                            rowB=find(MPOL(:,6)==d(i));
                            rowM=find(MPOL(:,8)==d(i));
                            rowN=find(MPOL(:,9)==d(i));
                            rowFLAGSxDecays=[rowFLAGSxDecays; rowA; rowB; rowM; rowN];
                            rowFLAGSxDecays=unique(rowFLAGSxDecays);
                        end
                    end
                end
            end

            if isempty(rowFLAGSxDecays)
                if ~isempty(app.Tree.SelectedNodes)
                    selectedNodes = app.Tree.SelectedNodes;
                    SetPdepthSide=app.SetPdepthSide;
                    [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                    hSet=height(SetPdepthSide{m,n});
                    %% Find row of brushed points in Stripe (if present)
                    for  l2=1:length(app.UIAxes3_2.Children)
                        if app.UIAxes3_2.Children(l2).Marker=='o'

                            d2=find(app.UIAxes3_2.Children(l2).BrushData==1);
                            for i=1:length(d2)
                                XData=app.UIAxes3_2.Children(l2).XData(d2(i));
                                row=find(SetPdepthSide{m,n}(:,2)==XData);
                                rowFLAGSxDecays=[rowFLAGSxDecays; SetPdepthSide{m,n}(row,1)];
                                rowFLAGSxDecays=unique(rowFLAGSxDecays);
                            end
                        end
                    end
                end
            end
            app.rowFLAGSxDecays=rowFLAGSxDecays;
        end

        % IDENTIFY THE QUADRUPOLE FROM THE SELECTED POINTS (row in qui file) TO CHANGE FLAG (RES):
        % first search in data profiles, if there aren't selected points search in
        % Pseudosection, until a selection is found
        % OUTPUT: rowFLAGSxRhoa = array with the number of the rows in MPOL of
        % the multipoles selected

        function FindSelectedRhoa(app)
            TAB=app.TAB;
            MPOL=app.MPOL;
            NGates=app.NGates;
            MPOLRhoaShift=app.MPOLRhoaShift;

            rowFLAGS=int16.empty;
            colFLAGS=cell(height(MPOL),1);
            rowFLAGSxRhoa=int16.empty;

            %% Find row of brushed points in Profiles
            for l=1:length(app.UIAxes2.Children)
                d=find(app.UIAxes2.Children(l).BrushData==1);
                if ~isempty(d)
                    for i=1:length(d)
                        XData=app.UIAxes2.Children(l).XData(d(i));
                        YData=app.UIAxes2.Children(l).YData(d(i));
                        row=find(MPOL(:,10)==XData & abs(MPOLRhoaShift(:,1))==YData);
                        rowFLAGSxRhoa=[rowFLAGSxRhoa; row];
                        rowFLAGSxRhoa=unique(rowFLAGSxRhoa);
                    end
                end
            end


            %% Find row of brushed points in Pseudosection
            if isempty(rowFLAGSxRhoa)
                for l=1:length(app.UIAxes3.Children)
                    d=find(app.UIAxes3.Children(l).BrushData==1);
                    if ~isempty(d)
                        for i=1:length(d)
                            XData=app.UIAxes3.Children(l).XData(d(i));
                            YData=app.UIAxes3.Children(l).YData(d(i));
                            row=find(MPOL(:,10)==XData & MPOL(:,12)==YData);
                            rowFLAGSxRhoa=[rowFLAGSxRhoa; row];
                            rowFLAGSxRhoa=unique(rowFLAGSxRhoa);
                        end
                    end
                end
            end

            app.rowFLAGSxRhoa=rowFLAGSxRhoa;
        end


        % IDENTIFY THE QUADRUPOLE FROM THE SELECTED POINTS (row in qui file) TO CHANGE THE FLAG (GATES):
        % first search in decays, then in Positions in Data Stripe, until a selection is found
        % OUTPUT:
        %   - rowFLAGS = array with the number of the rows in MPOL of the multipoles selected
        %   - colFLAGS = cell array; at the rows in rowFLAGS are stored the
        %   gates selected for that quadrupole


        function FindSelectedGates(app)
            %% Find Gate in MPOL (Gate numbers). ATTENCTION: row number
            % in colFLAGS (cells) correspond with row number in MPOL.

            TAB=app.TAB;
            MPOL=app.MPOL;
            NGates=app.NGates;


            rowFLAGS=int16.empty;
            colFLAGS=cell(height(MPOL),1);
            rowFLAGSxRhoa=int16.empty;


            %% Find row and col of brushed points in Decays (if present)
            for l2=1:length(app.UIAxes4.Children)
                if app.UIAxes4.Children(l2).Marker=='o'
                    d2=find(app.UIAxes4.Children(l2).BrushData==1);
                    for i=1:length(d2)
                        row=str2num(app.UIAxes4.Children(l2).Tag);
                        rowFLAGS=[rowFLAGS; row];
                        rowFLAGS=unique(rowFLAGS);
                        XData=app.UIAxes4.Children(l2).XData(d2(i));
                        NGateXData=find(TAB{7}(:,4)==XData);
                        colFLAGS{row,1}=[colFLAGS{row,1} NGateXData];
                        colFLAGS{row,1}=unique(colFLAGS{row,1});
                    end
                end
            end


            if isempty(rowFLAGS)
                selectedNodes = app.Tree.SelectedNodes;
                SetPdepthSide=app.SetPdepthSide;
                [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                hSet=height(SetPdepthSide{m,n});
                %% Find row and col of brushed points in Stripe (if present)
                for  l2=1:length(app.UIAxes3_2.Children)
                    if app.UIAxes3_2.Children(l2).Marker=='o'

                        d2=find(app.UIAxes3_2.Children(l2).BrushData==1);
                        for i=1:length(d2)
                            XData=app.UIAxes3_2.Children(l2).XData(d2(i));
                            row=find(SetPdepthSide{m,n}(:,2)==XData);
                            rowFLAGS=[rowFLAGS; SetPdepthSide{m,n}(row,1)];
                            rowFLAGS=unique(rowFLAGS);

                            colFLAGS{SetPdepthSide{m,n}(row,1),1}=[colFLAGS{SetPdepthSide{m,n}(row,1),1} str2num(app.UIAxes3_2.Children(l2).Tag)];
                            colFLAGS{SetPdepthSide{m,n}(row,1),1}=unique(colFLAGS{SetPdepthSide{m,n}(row,1),1});
                        end
                    end
                end
            end

            app.rowFLAGS=rowFLAGS;
            app.colFLAGS=colFLAGS;
        end


        % TO SHOW IN POSITIONS, DATA PROFILES, PSEUDOSECTION AND IN GIS MAP
        % THE MEASUREMENTS THAT ARE SHOWN IN DECAYS.
        %Shownpositions, Shownprofile, ShownPseudosec are plots that
        %superimpose the plot of all the data and are deleted and recreated
        %when a new selection is made
        % Q is sent to gisapp with only the coordinates of the selected
        % electrodes

        function Shown(app)
            ShownPositions=app.ShownPositions;
            ShownProfile=app.ShownProfile;
            ShownPseudosec=app.ShownPseudosec;
            MPOL=app.MPOL;
            DIST=app.DIST;
            NGates=app.NGates;
            SetPdepthSide=app.SetPdepthSide;
            checkedNodes = app.Tree.CheckedNodes;
            MPOLRhoaShift=app.MPOLRhoaShift;
            ROWSChecked=app.ROWSChecked;
            FLAGS=app.FLAGS;
            kMid=app.kMid;

            if ~isempty(ShownPositions)
                delete (ShownPositions);
            end
            if ~isempty(ShownProfile)
                delete (ShownProfile);
            end
            if ~isempty(ShownPseudosec)
                delete (ShownPseudosec);
            end

            rowFLAGSxDecays=app.rowFLAGSxDecays;

            Q=[];
            ShownPositions=gobjects(0);
            for r=1:height(rowFLAGSxDecays)
                A=MPOL(rowFLAGSxDecays(r),4);
                B=MPOL(rowFLAGSxDecays(r),6);
                N=MPOL(rowFLAGSxDecays(r),8);
                M=MPOL(rowFLAGSxDecays(r),9);
                Q{r,1}=[A B M N];
                ShownPositions=[ShownPositions plot(app.UIAxes,DIST(Q{r},2),app.TAB{4}(Q{r},5),'o','MarkerEdgeColor',[0.71765     0.27451           1],'MarkerFaceColor',[0.71765     0.27451           1],'MarkerSize',3, "LineStyle","none")];
            end

            ShownProfile=gobjects(0);
            for i=1:height(rowFLAGSxDecays)
                if ismember(rowFLAGSxDecays(i),ROWSChecked)
                    if ~(FLAGS(rowFLAGSxDecays(i),1,kMid)==1 && app.HiderejectedpointsCheckBox.Value==1)
                        ShownProfile(i)=plot(app.UIAxes2,MPOL(rowFLAGSxDecays(i),10),MPOLRhoaShift(rowFLAGSxDecays(i),1),'o','MarkerEdgeColor',[0.71765     0.27451           1],'MarkerFaceColor',[0.71765     0.27451           1],'MarkerSize',3,"LineStyle","none");
                    end
                end
            end

            ShownPseudosec=gobjects(0);
            for i=1:height(rowFLAGSxDecays)
                if ~(FLAGS(rowFLAGSxDecays(i),1,kMid)==1 && app.HiderejectedpointsCheckBox.Value==1)
                    ShownPseudosec(i)=plot(app.UIAxes3,MPOL(rowFLAGSxDecays(i),10),MPOL(rowFLAGSxDecays(i),12),'o','MarkerEdgeColor',[1 1 1],'MarkerFaceColor',[0.71765     0.27451           1],'MarkerSize',4,"LineStyle","none");
                end
            end

            app.ShownPositions=ShownPositions;
            app.ShownProfile=ShownProfile;
            app.ShownPseudosec=ShownPseudosec;


            importdata_processingapp(app.callingapp,Q,app.TAB);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gisapp, TAB, MPOL, RAWDATA, DIST, QuiFile)

            app.callingapp=gisapp;

            app.UIFigure.Name = QuiFile; %The name of the qui file is displayed in the window name

            NGates=TAB{7}(height(TAB{7}),1);

            %% Find quadrupole side

            %             tol=0.05;
            %             PdepthSorted=sort(uniquetol(app.MPOL(:,12),tol));
            %             XfocusSorted=sort(unique(app.MPOL(:,10));
            PdepthSorted=sort(unique(MPOL(:,12)));
            XfocusSorted=sort(unique(MPOL(:,10)));

            NMpoles=height(MPOL);
            for l=1:NMpoles
                Qdrp=sort([MPOL(l,4) MPOL(l,6) MPOL(l,8) MPOL(l,9)]); %[A B M N]
                if Qdrp(3)-Qdrp(1)<Qdrp(4)-Qdrp(2)
                    QdrpSide(l,1)=1; % Left
                elseif Qdrp(3)-Qdrp(1)>Qdrp(4)-Qdrp(2)
                    QdrpSide(l,1)=2; % Right
                elseif Qdrp(3)-Qdrp(1)==Qdrp(4)-Qdrp(2)
                    QdrpSide(l,1)=0;  % - (Symmetric)
                end
            end

            %% Divide into Pseudodepth sets

            for i=1:height(PdepthSorted)
                SetPdepth{i}=find(MPOL(:,12)==PdepthSorted(i,1));
            end

            SetPdepth=SetPdepth';


            for m=1:height(SetPdepth)
                for n=1:height(SetPdepth{m})
                    SetPdepth{m}(n,2)=MPOL(SetPdepth{m}(n,1),10);
                    SetPdepth{m}(n,3)=MPOL(SetPdepth{m}(n,1),12);
                    SetPdepth{m}(n,4)=MPOL(SetPdepth{m}(n,1),16+NGates*2+1);
                    SetPdepth{m}(n,5)=QdrpSide(SetPdepth{m}(n,1),1);
                end
            end

            for j=1:height(SetPdepth)
                SetPdepth{j}=sortrows(SetPdepth{j},2);
            end

            SetPdepthSide=cell(height(SetPdepth),3);

            for i=1:height(SetPdepth)
                for j=1:height(SetPdepth{i})
                    if SetPdepth{i}(j,5)==0
                        SetPdepthSide{i,1}=[SetPdepthSide{i,1};SetPdepth{i}(j,:)];
                    elseif SetPdepth{i}(j,5)==1
                        SetPdepthSide{i,2}=[SetPdepthSide{i,2};SetPdepth{i}(j,:)];
                    elseif SetPdepth{i}(j,5)==2
                        SetPdepthSide{i,3}=[SetPdepthSide{i,3};SetPdepth{i}(j,:)];
                    end
                end
            end


            %% Create nodes in app.Tree and an array with names to display
            NameSetPdepthSide=strings(height(SetPdepth),3);

            for m=1:height(SetPdepthSide)
                for n=1:3
                    if ~isempty(SetPdepthSide{m,n})
                        nodeDataProfile{m,n}=uitreenode(app.Tree);

                        if SetPdepthSide{m,n}(:,5)==0
                            NameSetPdepthSide(m,n)=num2str(SetPdepthSide{m,n}(1,3));

                        elseif SetPdepthSide{m,n}(:,5)==1
                            NameSetPdepthSide(m,n)=append(num2str(SetPdepthSide{m,n}(1,3)),' Left');

                        elseif SetPdepthSide{m,n}(:,5)==2
                            NameSetPdepthSide(m,n)=append(num2str(SetPdepthSide{m,n}(1,3)),' Right');

                        end
                        nodeDataProfile{m,n}.Text=NameSetPdepthSide(m,n);
                        app.Tree.CheckedNodes=[app.Tree.CheckedNodes; nodeDataProfile{m,n}];
                    end
                end
            end

            %% 3-dimensions array with flags for resistivity and all the gates
            kEnd=20;
            kMid=10;
            FLAGS=zeros(height(MPOL),NGates,kEnd);
            FLAGS(:,:,:)=NaN;
            FLAGS(:,:,kMid)=MPOL(:,17+NGates:16+NGates*2);

            %% List of rows in MPOL that correspond to a checked node
            ROWSChecked=1:1:height(MPOL);
            ROWSChecked=ROWSChecked';


            %% Set default settings and axis
            %% Positions
            hold(app.UIAxes,'on')
            settingsPositions=cell(2,1);

            settingsPositions{1}(1,:)=[min(DIST(:,2)) max(DIST(:,2))];
            app.UIAxes.XLim=settingsPositions{1}(1,:);

            settingsPositions{1}(2,:)=[min(TAB{4}(:,5))-0.5 max(TAB{4}(:,5))+0.5];
            app.UIAxes.YLim=settingsPositions{1}(2,:);

            settingsPositions{2}=settingsPositions{1};
            app.settingsPositions=settingsPositions;


            %% Profiles
            app.UIAxes2.YScale='lin';
            hold(app.UIAxes2,'on')
            CShiftvalue = app.CurveshiftfactorSpinner.Value;
            settingsProfile=cell(2,1);

            settingsProfile{1}(1,:)=[min(DIST(:,2)) max(DIST(:,2))];
            app.UIAxes2.XLim=settingsProfile{1}(1,:);

            settingsProfile{1}(2,:)=[min(MPOL(:,17+NGates*2)) max(MPOL(:,17+NGates*2))];
            app.UIAxes2.YLim=settingsProfile{1}(2,:);

            settingsProfile{2}=settingsProfile{1};
            app.settingsProfile=settingsProfile;


            %% Pseudosection
            hold(app.UIAxes3,'on')
            settingsPseudosec=cell(2,1);

            settingsPseudosec{1}(1,:)=[min(DIST(:,2)) max(DIST(:,2))];
            app.UIAxes3.XLim=settingsPseudosec{1}(1,:);

            settingsPseudosec{1}(2,:)=[min(MPOL(:,12)) max(MPOL(:,12))];
            app.UIAxes3.YLim=settingsPseudosec{1}(2,:);

            settingsPseudosec{2}=settingsPseudosec{1};

            colorscaleLim=[0.001 1000];
            colormap (app.UIAxes3,jet);
            caxis(app.UIAxes3,colorscaleLim);
            c=colorbar(app.UIAxes3,'southoutside');
            c.Label.String = 'Rhoa [ohm m]';
            c.Label.FontSize = 7;

            %             settingsPseudosec{3}=strings(2,1);
            %             settingsPseudosec{3}='jet';
            %             settingsPseudosec{4}=[0.001 1000];
            app.settingsPseudosec=settingsPseudosec;


            %% Stripe
            app.UIAxes3_2.YScale='log';
            hold(app.UIAxes3_2,'on')
            settingsStripe=cell(2,1);

            IntStripe(1)=300;

            settingsStripe{3}(1,1)=IntStripe(1);
            settingsStripe{4}(1,1)=IntStripe(1);

            settingsStripe{1}(1,:)=[min(DIST(:,2)) 300];
            app.UIAxes3_2.XLim=settingsStripe{1}(1,:);

            settingsStripe{1}(2,:)=[1e-3 1e5];
            app.UIAxes3_2.YLim=settingsStripe{1}(2,:);

            settingsStripe{2}=settingsStripe{1};

            app.IntStripe=IntStripe;
            app.settingsStripe=settingsStripe;


            %% Decays
            app.UIAxes4.XScale='log';
            app.UIAxes4.YScale='log';
            hold(app.UIAxes4,'on')
            settingsDecay=cell(4,1);
            settingsDecay{1}(1,:)=[1e-3 TAB{7}(NGates,4)+1]; % settingsDecay{1}=[Xmax Xmin;Ymax Ymin]
            app.UIAxes4.XLim=settingsDecay{1}(1,:);

            settingsDecay{1}(2,:)=[1e-3 1e5];
            app.UIAxes4.YLim=settingsDecay{1}(2,:);
            settingsDecay{2}=settingsDecay{1};
            app.settingsDecay=settingsDecay;


            %% Create properties
            app.TAB=TAB;
            app.MPOL=MPOL;
            app.RAWDATA=RAWDATA;
            app.DIST=DIST;
            app.QuiFile=QuiFile;
            app.NameSetPdepthSide=NameSetPdepthSide;
            app.SetPdepthSide=SetPdepthSide;
            app.SetPdepth=SetPdepth;
            app.nodeDataProfile=nodeDataProfile;
            app.FLAGS=FLAGS;
            app.NGates=NGates;
            app.ROWSChecked=ROWSChecked;
            app.kMid=kMid;
            app.kEnd=kEnd;


            %% Plot
            app.EnableKeyboardShortCuts;
            app.PlotPosition;
            app.PlotDataProfile;
            app.PlotPseudosection;

        end

        % Button pushed function: SettingsButton_pseudosec
        function SettingsButton_pseudosecPushed(app, event)
            settingsPseudosec=app.settingsPseudosec;
            app.SettingsButton_pseudosec.Enable='off'
            app.settings_pseudosecapp=settings_pseudosecapp(app,settingsPseudosec);
        end

        % Button pushed function: SettingsButton_stripe
        function SettingsButton_stripePushed(app, event)
            settingsStripe=app.settingsStripe;
            app.SettingsButton_stripe.Enable='off'
            app.settings_stripeapp=settings_stripeapp(app,settingsStripe);
        end

        % Button pushed function: SettingsButton_decay
        function SettingsButton_decayPushed(app, event)
            settingsDecay=app.settingsDecay;
            app.SettingsButton_decay.Enable='off'
            app.settings_decayapp=settings_decayapp(app,settingsDecay);
        end

        % Button pushed function: SettingsButton_profile
        function SettingsButton_profilePushed(app, event)
            settingsProfile=app.settingsProfile;
            app.SettingsButton_profile.Enable='off'
            app.settings_profileapp=settings_profileapp(app,settingsProfile);
        end

        % Button pushed function: SettingsButton_positions
        function SettingsButton_positionsPushed(app, event)
            settingsPositions=app.settingsPositions;
            app.SettingsButton_positions.Enable='off'
            app.settings_positionsapp=settings_positionsapp(app,settingsPositions);
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            % SHOW OR HIDE THE SETS IN DATA PROFILES BASED ON CHECKED NODES
            % IN THE SETS TREE

            hold(app.UIAxes2,'on')
            cla(app.UIAxes2)
            checkedNodes = app.Tree.CheckedNodes;
            selectedNodes = app.Tree.SelectedNodes;
            plotDataProfile=app.plotDataProfile;
            plotSelectedProfile=app.plotSelectedProfile;
            %             psecSelectedProfile=app.psecSelectedProfile;
            SetPdepthSide=app.SetPdepthSide;
            CShiftvalue = app.CurveshiftfactorSpinner.Value;
            ROWSChecked=app.ROWSChecked;

            [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);

            app.PlotDataProfile;
            if ismember(selectedNodes,checkedNodes)==0
                delete(plotSelectedProfile);

                for f=1:height(SetPdepthSide{m,n})
                    d(f)=find(ROWSChecked==SetPdepthSide{m,n}(f,1));
                    ROWSChecked(d(f),:)=[];
                end


            else
                index=height(SetPdepthSide)-(m-1);
                %                 RhoaShift=zeros(height(SetPdepthSide{m,n}),1);

                for p=1:height(SetPdepthSide{m,n})
                    %                     RhoaShift(p,1)=SetPdepthSide{m,n}(p,4).*CShiftvalue^index;
                    ROWSChecked=[ROWSChecked; SetPdepthSide{m,n}(p,1)];
                end
                %                     plotDataProfile{m,n}=plot(app.UIAxes2,SetPdepthSide{m,n}(:,2),RhoaShift(:,1),'k.-');
                plotSelectedProfile=plot(app.UIAxes2,SetPdepthSide{m,n}(:,2),SetPdepthSide{m,n}(:,4)*CShiftvalue^index,'.-','LineWidth',5,'Color',[0 0.5 1 0.3]);

            end

            app.plotSelectedProfile=plotSelectedProfile;
            app.ROWSChecked=ROWSChecked;


        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            checkedNodes = app.Tree.CheckedNodes;
            SetPdepthSide=app.SetPdepthSide;
            plotSelectedProfile=app.plotSelectedProfile;
            NGates=app.NGates;
            DIST=app.DIST;
            IntStripe=app.IntStripe;

            [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);

            if ~isempty(plotSelectedProfile)
                delete (plotSelectedProfile);
            end

            %% Hilight SelectedProfile
            if ismember(selectedNodes,checkedNodes)==1

                index=height(SetPdepthSide)-(m-1);
                CShiftvalue = app.CurveshiftfactorSpinner.Value;

                plotSelectedProfile=plot(app.UIAxes2,SetPdepthSide{m,n}(:,2),SetPdepthSide{m,n}(:,4)*CShiftvalue^index,'.-','LineWidth',5,'Color',[0 0.5 1 0.3]);

                app.plotSelectedProfile=plotSelectedProfile;
            end

            %% Change X axis
            app.settingsStripe{2}=IntStripe(1)
            startInt=min(SetPdepthSide{m,n}(:,2));
            if (startInt+IntStripe(1))<max(DIST(:,2))
                endInt=startInt+IntStripe(1);
            else
                startInt=max(DIST(:,2))-IntStripe(1);
                endInt=max(DIST(:,2));
            end

            MSet=cell(NGates,1);
            Xfocus=cell(NGates,1);
            Std=cell(NGates,1);

            app.settingsStripe{1}(1,:)=[startInt-1 endInt+1];
            app.UIAxes3_2.XLim=app.settingsStripe{1}(1,:);
            app.settingsStripe{2}=app.settingsStripe{1};

            IntStripe(2)=startInt;
            IntStripe(3)=endInt;

            app.MSet=MSet;
            app.Xfocus=Xfocus;
            app.Std=Std;
            app.IntStripe=IntStripe;

            %% Plot data stripe
            app.PlotDataStripe;

        end

        % Button pushed function: Previous
        function PreviousPushed(app, event)
            % SHOW THE DECAYS IN THE SEGMENT BEFORE THE CURRENT ONE IN DATA
            % STRIPE

            selectedNodes = app.Tree.SelectedNodes;
            DIST=app.DIST;
            IntStripe=app.IntStripe;

            [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);

            %% Change X axis
            startInt=IntStripe(2)-IntStripe(1);
            if startInt>min(DIST(:,2))
                endInt=IntStripe(2);
            else
                startInt=min(DIST(:,2));
                endInt=min(DIST(:,2))+IntStripe(1);
            end


            app.settingsStripe{1}(1,:)=[startInt-1 endInt+1];
            app.UIAxes3_2.XLim=app.settingsStripe{1}(1,:);

            app.settingsStripe{2}=app.settingsStripe{1};

            IntStripe(2)=startInt;
            IntStripe(3)=endInt;

            app.IntStripe=IntStripe;

        end

        % Button pushed function: Next
        function NextPushed(app, event)
            % SHOW THE DECAYS IN THE SEGMENT AFTER THE CURRENT ONE IN DATA
            % STRIPE

            selectedNodes = app.Tree.SelectedNodes;
            DIST=app.DIST;
            IntStripe=app.IntStripe;

            [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);

            %% Change X axis
            startInt=IntStripe(3);
            if (startInt+IntStripe(1))<max(DIST(:,2))
                endInt=startInt+IntStripe(1);
            else
                startInt=max(DIST(:,2))-IntStripe(1);
                endInt=max(DIST(:,2));
            end

            app.settingsStripe{1}(1,:)=[startInt-1 endInt+1];
            app.UIAxes3_2.XLim=app.settingsStripe{1}(1,:);

            app.settingsStripe{2}=app.settingsStripe{1};

            IntStripe(2)=startInt;
            IntStripe(3)=endInt;

            app.IntStripe=IntStripe;

        end

        % Value changed function: CurveshiftfactorSpinner
        function CurveshiftfactorSpinnerValueChanged(app, event)
            % RE-PLOT THE DATA PROFILES WHEN THE SHIFT FACTOR IS CHANGED

            app.PlotDataProfile;
            app.Shown;

            if app.CurveshiftfactorSpinner.Value>1
                app.UIAxes2.YScale='log';
                ylabel(app.UIAxes2, 'Rhoa [ - ]')
            else
                app.UIAxes2.YScale='lin';
                ylabel(app.UIAxes2, 'Rhoa [ohm m]')
            end

            MPOL=app.MPOL;
            settingsProfile=app.settingsProfile;
            MPOLRhoaShift=app.MPOLRhoaShift;
            settingsProfile{1}(2,:)=[min(MPOLRhoaShift) max(MPOLRhoaShift)];
            app.UIAxes2.YLim=settingsProfile{1}(2,:);
            settingsProfile{2}= settingsProfile{1};
            app.settingsProfile=settingsProfile;

            app.Shown;

            %% MOVED TO app.PlotDataProfile
            %             rowFLAGSxDecays=app.rowFLAGSxDecays;
            %             ROWSChecked=app.ROWSChecked;
            %
            %             kMid=app.kMid;
            %             FLAGS=app.FLAGS;
            %             ShownProfile=gobjects(0);
            %             for i=1:height(rowFLAGSxDecays)
            %                 if ismember(rowFLAGSxDecays(i,1),ROWSChecked)
            %                     if ~(FLAGS(rowFLAGSxDecays(i),1,Mid)==1 && app.HiderejectedpointsCheckBox.Value==1)
            %                         ShownProfile(i)=plot(app.UIAxes2,MPOL(rowFLAGSxDecays(i),10),MPOLRhoaShift(rowFLAGSxDecays(i),1),'o','MarkerEdgeColor',[1 0 0],'MarkerFaceColor',[1 0 0],'MarkerSize',2);
            %                     end
            %                 end
            %             end
            %             app.ShownProfile=ShownProfile;
        end

        % Value changed function: HiderejectedpointsCheckBox
        function HiderejectedpointsCheckBoxValueChanged(app, event)
            % DON'T SHOW THE GREY POINTS AND LINES

            app.rowFLAGSxDecays
            app.PlotDecay;
            app.PlotDataProfile;
            app.PlotPseudosection;
            app.Shown;
            if ~isempty(app.Tree.SelectedNodes)
                app.PlotDataStripe;
            end
        end

        % Button pushed function: ShowAllButton
        function ShowAllButtonPushed(app, event)
            % SHOW ALL SETS IN DATA PROFILES

            hold(app.UIAxes2,'on')
            plotDataProfile=app.plotDataProfile;
            plotSelectedProfile=app.plotSelectedProfile;
            SetPdepthSide=app.SetPdepthSide;
            CShiftvalue = app.CurveshiftfactorSpinner.Value;
            NameSetPdepthSide=app.NameSetPdepthSide;
            nodeDataProfile=app.nodeDataProfile;


            for m=1:height(SetPdepthSide)
                for n=1:3
                    if ~isempty(SetPdepthSide{m,n})
                        app.Tree.CheckedNodes=[app.Tree.CheckedNodes; nodeDataProfile{m,n}];
                    end
                end
            end

            ROWSChecked=[];
            ROWSChecked=1:1:height(app.MPOL);
            ROWSChecked=ROWSChecked';

            app.ROWSChecked=ROWSChecked;
            app.PlotDataProfile;
            app.Shown;
        end

        % Button pushed function: HideAllButton
        function HideAllButtonPushed(app, event)
            % HIDE ALL SETS IN DATA PROFILES

            checkedNodes = app.Tree.CheckedNodes;
            selectedNodes = app.Tree.SelectedNodes;
            plotDataProfile=app.plotDataProfile;
            plotSelectedProfile=app.plotSelectedProfile;
            SetPdepthSide=app.SetPdepthSide;
            CShiftvalue = app.CurveshiftfactorSpinner.Value;

            ROWSChecked=[];
            app.ROWSChecked=ROWSChecked;

            app.Tree.CheckedNodes=[];
            cla(app.UIAxes2);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            if isequal(char(event.Modifier),'alt')

                switch key
                    case 'q'            % remove the selected measurements

                        %%
                        kMid=app.kMid;
                        kEnd=app.kEnd;

                        app.FindSelectedRhoa;

                        if  ~isempty(app.rowFLAGSxRhoa)
                            FLAGS=app.FLAGS;
                            rowFLAGSxRhoa=app.rowFLAGSxRhoa;
                            NGates=app.NGates;
                            %%

                            for k=2:kMid
                                FLAGS(:,:,k-1)=FLAGS(:,:,k);
                            end
                            for i=1:height(rowFLAGSxRhoa)
                                FLAGS(rowFLAGSxRhoa(i),1,kMid)=1;
                            end

                            %%
                            app.FLAGS=FLAGS;

                            app.PlotPseudosection;
                            app.PlotDataProfile;
                            app.Shown;
                            %%
                        else
                            app.FindSelectedGates;
                            if ~isempty(app.rowFLAGS)
                                FLAGS=app.FLAGS;
                                rowFLAGS=app.rowFLAGS;
                                colFLAGS=app.colFLAGS;
                                NGates=app.NGates;
                                %%

                                for k=2:kMid
                                    FLAGS(:,:,k-1)=FLAGS(:,:,k);
                                end
                                for i=1:height(rowFLAGS)
                                    for j=1:length(colFLAGS{rowFLAGS(i),1})
                                        FLAGS(rowFLAGS(i),colFLAGS{rowFLAGS(i),1}(j),kMid)=1;
                                    end
                                end

                                %%
                                app.FLAGS=FLAGS;
                                app.PlotDecay;
                                if ~isempty(app.Tree.SelectedNodes)
                                    app.PlotDataStripe;
                                end
                                %%
                            end
                        end

                    case 'a'        %  restore the selected measurements

                        %%
                        kMid=app.kMid;
                        kEnd=app.kEnd;

                        app.FindSelectedRhoa;

                        if  ~isempty(app.rowFLAGSxRhoa)
                            FLAGS=app.FLAGS;
                            rowFLAGSxRhoa=app.rowFLAGSxRhoa;
                            NGates=app.NGates;
                            %%

                            for k=2:kMid
                                FLAGS(:,:,k-1)=FLAGS(:,:,k);
                            end
                            for i=1:height(rowFLAGSxRhoa)
                                FLAGS(rowFLAGSxRhoa(i),1,kMid)=0;
                            end

                            %%
                            app.FLAGS=FLAGS;

                            app.PlotPseudosection;
                            app.PlotDataProfile;
                            app.Shown;
                            %%
                        else
                            app.FindSelectedGates;
                            if ~isempty(app.rowFLAGS)
                                FLAGS=app.FLAGS;
                                rowFLAGS=app.rowFLAGS;
                                colFLAGS=app.colFLAGS;
                                NGates=app.NGates;
                                %%

                                for k=2:kMid
                                    FLAGS(:,:,k-1)=FLAGS(:,:,k);
                                end
                                for i=1:height(rowFLAGS)
                                    for j=1:length(colFLAGS{rowFLAGS(i),1})
                                        FLAGS(rowFLAGS(i),colFLAGS{rowFLAGS(i),1}(j),kMid)=0;
                                    end
                                end

                                %%
                                app.FLAGS=FLAGS;
                                app.PlotDecay;
                                if ~isempty(app.Tree.SelectedNodes)
                                    app.PlotDataStripe;
                                end
                                %%
                            end
                        end

                    case 'w'            % remove from the first gate in
                        % the plot to the last gate selected, for the decays
                        % in which there is a selection

                        %%
                        kMid=app.kMid;
                        kEnd=app.kEnd;

                        app.FindSelectedGates;

                        FLAGS=app.FLAGS;
                        rowFLAGS=app.rowFLAGS;
                        colFLAGS=app.colFLAGS;
                        NGates=app.NGates;
                        %%

                        for k=2:kMid
                            FLAGS(:,:,k-1)=FLAGS(:,:,k);
                        end
                        for i=1:height(rowFLAGS)
                            Last=length(colFLAGS{rowFLAGS(i),1});
                            FLAGS(rowFLAGS(i),1:colFLAGS{rowFLAGS(i),1}(Last),kMid)=1;
                        end

                        %%
                        app.FLAGS=FLAGS;
                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                        end
                        %%

                    case 's'            % restore from the first gate in
                        % the plot to the last gate selected, for the decays
                        % in which there is a selection

                        %%
                        kMid=app.kMid;
                        kEnd=app.kEnd;

                        app.FindSelectedGates;

                        FLAGS=app.FLAGS;
                        rowFLAGS=app.rowFLAGS;
                        colFLAGS=app.colFLAGS;
                        NGates=app.NGates;
                        %%

                        for k=2:kMid
                            FLAGS(:,:,k-1)=FLAGS(:,:,k);
                        end
                        for i=1:height(rowFLAGS)
                            Last=length(colFLAGS{rowFLAGS(i),1});
                            FLAGS(rowFLAGS(i),1:colFLAGS{rowFLAGS(i),1}(Last),kMid)=0;
                        end

                        %%
                        app.FLAGS=FLAGS;
                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                        end
                        %%

                    case 'e'           % remove from the first selected gate
                        % to the last gate in the plot, for the decays
                        % in which there is a selection

                        %%
                        kMid=app.kMid;
                        kEnd=app.kEnd;

                        app.FindSelectedGates;

                        FLAGS=app.FLAGS;
                        rowFLAGS=app.rowFLAGS;
                        colFLAGS=app.colFLAGS;
                        NGates=app.NGates;
                        %%

                        for k=2:kMid
                            FLAGS(:,:,k-1)=FLAGS(:,:,k);
                        end
                        for i=1:height(rowFLAGS)
                            FLAGS(rowFLAGS(i),colFLAGS{rowFLAGS(i),1}(1):NGates,kMid)=1;
                        end

                        %%
                        app.FLAGS=FLAGS;
                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                        end
                        %%

                    case 'd'            % restore from the first selected gate
                        % to the last gate in the plot, for the decays
                        % in which there is a selection

                        %%
                        kMid=app.kMid;
                        kEnd=app.kEnd;

                        app.FindSelectedGates;

                        FLAGS=app.FLAGS;
                        rowFLAGS=app.rowFLAGS;
                        colFLAGS=app.colFLAGS;
                        NGates=app.NGates;
                        %%

                        for k=2:kMid
                            FLAGS(:,:,k-1)=FLAGS(:,:,k);
                        end
                        for i=1:height(rowFLAGS)
                            FLAGS(rowFLAGS(i),colFLAGS{rowFLAGS(i),1}(1):NGates,kMid)=0;
                        end

                        %%
                        app.FLAGS=FLAGS;
                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                        end
                        %%

                    case 'n'            % remove the selected gates, if negative

                        %%
                        kMid=app.kMid;
                        kEnd=app.kEnd;

                        app.FindSelectedGates;

                        FLAGS=app.FLAGS;
                        MPOL=app.MPOL;
                        rowFLAGS=app.rowFLAGS;
                        colFLAGS=app.colFLAGS;
                        NGates=app.NGates;
                        %%

                        for k=2:kMid
                            FLAGS(:,:,k-1)=FLAGS(:,:,k);
                        end
                        for i=1:height(rowFLAGS)
                            for j=1:length(colFLAGS{rowFLAGS(i),1})
                                if MPOL(rowFLAGS(i),19+NGates*2+colFLAGS{rowFLAGS(i),1}(j))<0
                                    FLAGS(rowFLAGS(i),colFLAGS{rowFLAGS(i),1}(j),kMid)=1;
                                end
                            end
                        end

                        %%
                        app.FLAGS=FLAGS;
                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                        end
                        %%

                    case 'm'            % restore the selected gates, if positive

                        %%
                        kMid=app.kMid;
                        kEnd=app.kEnd;

                        app.FindSelectedGates;

                        FLAGS=app.FLAGS;
                        MPOL=app.MPOL;
                        rowFLAGS=app.rowFLAGS;
                        colFLAGS=app.colFLAGS;
                        NGates=app.NGates;
                        %%

                        for k=2:kMid
                            FLAGS(:,:,k-1)=FLAGS(:,:,k);
                        end
                        for i=1:height(rowFLAGS)
                            for j=1:length(colFLAGS{rowFLAGS(i),1})
                                if MPOL(rowFLAGS(i),19+NGates*2+colFLAGS{rowFLAGS(i),1}(j))>0
                                    FLAGS(rowFLAGS(i),colFLAGS{rowFLAGS(i),1}(j),kMid)=0;
                                end
                            end
                        end

                        %%
                        app.FLAGS=FLAGS;
                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                        end
                        %%

                end

            elseif isequal(char(event.Modifier),'shift')
                switch key
                    case 'q'            % remove all the gates from the decays
                        % in which there is a selection

                        %%
                        kMid=app.kMid;
                        kEnd=app.kEnd;

                        app.FindSelectedGates;

                        FLAGS=app.FLAGS;
                        rowFLAGS=app.rowFLAGS;
                        colFLAGS=app.colFLAGS;
                        NGates=app.NGates;
                        %%

                        for k=2:kMid
                            FLAGS(:,:,k-1)=FLAGS(:,:,k);
                        end
                        for i=1:height(rowFLAGS)
                            if ~isempty(colFLAGS{rowFLAGS(i)})
                                FLAGS(rowFLAGS(i),:,kMid)=1;
                            end
                        end

                        %%
                        app.FLAGS=FLAGS;
                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                        end
                        %%

                    case 'a'            % restore all the gates from the decays
                        % in which there is a selection

                        %%
                        kMid=app.kMid;
                        kEnd=app.kEnd;

                        app.FindSelectedGates;

                        FLAGS=app.FLAGS;
                        rowFLAGS=app.rowFLAGS;
                        colFLAGS=app.colFLAGS;
                        NGates=app.NGates;
                        %%

                        for k=2:kMid
                            FLAGS(:,:,k-1)=FLAGS(:,:,k);
                        end
                        for i=1:height(rowFLAGS)
                            if ~isempty(colFLAGS{rowFLAGS(i)})
                                FLAGS(rowFLAGS(i),:,kMid)=0;
                            end
                        end

                        %%
                        app.FLAGS=FLAGS;
                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                        end
                        %%

                    case 'n'            % remove all the negative gates from
                        % the decays in which there is a selection

                        %%
                        kMid=app.kMid;
                        kEnd=app.kEnd;

                        app.FindSelectedGates;

                        FLAGS=app.FLAGS;
                        MPOL=app.MPOL;
                        rowFLAGS=app.rowFLAGS;
                        colFLAGS=app.colFLAGS;
                        NGates=app.NGates;
                        %%

                        for k=2:kMid
                            FLAGS(:,:,k-1)=FLAGS(:,:,k);
                        end
                        for i=1:height(rowFLAGS)
                            for j=1:NGates
                                if ~isempty(colFLAGS{rowFLAGS(i)})
                                    if MPOL(rowFLAGS(i),19+NGates*2+j)<0
                                        FLAGS(rowFLAGS(i),j,kMid)=1;
                                    end
                                end
                            end
                        end

                        %%
                        app.FLAGS=FLAGS;
                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                        end
                        %%

                    case 'm'        % restore all the positive gates from
                        % the decays in which there is a selection

                        %%
                        kMid=app.kMid;
                        kEnd=app.kEnd;

                        app.FindSelectedGates;

                        FLAGS=app.FLAGS;
                        MPOL=app.MPOL;
                        rowFLAGS=app.rowFLAGS;
                        colFLAGS=app.colFLAGS;
                        NGates=app.NGates;
                        %%

                        for k=2:kMid
                            FLAGS(:,:,k-1)=FLAGS(:,:,k);
                        end
                        for i=1:height(rowFLAGS)
                            for j=1:NGates
                                if ~isempty(colFLAGS{rowFLAGS(i)})
                                    if MPOL(rowFLAGS(i),19+NGates*2+j)>0
                                        FLAGS(rowFLAGS(i),j,kMid)=0;
                                    end
                                end
                            end
                        end

                        %%
                        app.FLAGS=FLAGS;
                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                        end
                        %%

                end

            elseif isequal(char(event.Modifier),'control')
                switch key

                    case 'z'            % undo

                        %%
                        kEnd=app.kEnd;
                        FLAGS=app.FLAGS;
                        %%

                        for k=kEnd:-1:2
                            FLAGS(:,:,k)=FLAGS(:,:,k-1);
                        end

                        %%
                        app.FLAGS=FLAGS;
                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                        end
                        %%

                    case 'y'            % redo

                        %%
                        kMid=app.kMid;
                        kEnd=app.kEnd;
                        FLAGS=app.FLAGS;
                        %%

                        if FLAGS(1,1,kMid+1)==0 | FLAGS(1,1,kMid+1)==1
                            for k=1:kEnd-1
                                FLAGS(:,:,k)=FLAGS(:,:,k+1);
                            end
                        end

                        %%
                        app.FLAGS=FLAGS;
                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                        end
                        %%

                    case 'q'            % remove all the gates selected
                        % from all the decays of the set
                        %%
                        kMid=app.kMid;
                        kEnd=app.kEnd;

                        app.FindSelectedGates;

                        rowFLAGS=app.rowFLAGS;
                        colFLAGS=app.colFLAGS;
                        FLAGS=app.FLAGS;

                        %%
                        selectedNodes = app.Tree.SelectedNodes;
                        SetPdepthSide=app.SetPdepthSide;
                        [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                        hSet=height(SetPdepthSide{m,n});


                        for k=2:kMid
                            FLAGS(:,:,k-1)=FLAGS(:,:,k);
                        end
                        for j=1:hSet
                            for i=1:height(rowFLAGS)
                                if ~isempty(colFLAGS{rowFLAGS(i)})
                                    FLAGS(SetPdepthSide{m,n}(j,1),colFLAGS{rowFLAGS(i)},kMid)=1;
                                end
                            end
                        end


                        %%
                        app.FLAGS=FLAGS;
                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                        end
                        %%

                    case 'a'            % restore all the gates selected
                        % from all the decays of the set
                        %%
                        kMid=app.kMid;
                        kEnd=app.kEnd;

                        app.FindSelectedGates;

                        rowFLAGS=app.rowFLAGS;
                        colFLAGS=app.colFLAGS;
                        FLAGS=app.FLAGS;

                        %%
                        selectedNodes = app.Tree.SelectedNodes;
                        SetPdepthSide=app.SetPdepthSide;
                        [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                        hSet=height(SetPdepthSide{m,n});


                        for k=2:kMid
                            FLAGS(:,:,k-1)=FLAGS(:,:,k);
                        end
                        for j=1:hSet
                            for i=1:height(rowFLAGS)
                                if ~isempty(colFLAGS{rowFLAGS(i)})
                                    FLAGS(SetPdepthSide{m,n}(j,1),colFLAGS{rowFLAGS(i)},kMid)=0;
                                end
                            end
                        end


                        %%
                        app.FLAGS=FLAGS;
                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                        end
                        %%

                    case 'n'            % remove all the gates selected
                        % from all the decays of the set, if negative
                        %%
                        kMid=app.kMid;
                        kEnd=app.kEnd;

                        app.FindSelectedGates;

                        rowFLAGS=app.rowFLAGS;
                        colFLAGS=app.colFLAGS;
                        FLAGS=app.FLAGS;
                        MPOL=app.MPOL;
                        NGates=app.NGates;

                        %%
                        selectedNodes = app.Tree.SelectedNodes;
                        SetPdepthSide=app.SetPdepthSide;
                        [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                        hSet=height(SetPdepthSide{m,n});


                        for k=2:kMid
                            FLAGS(:,:,k-1)=FLAGS(:,:,k);
                        end
                        for j=1:hSet
                            for i=1:height(rowFLAGS)
                                for l=1:length(colFLAGS{rowFLAGS(i)})
                                    %                                     if ~isempty(colFLAGS{rowFLAGS(i)})
                                    if MPOL(SetPdepthSide{m,n}(j,1),19+NGates*2+colFLAGS{rowFLAGS(i)}(l))<0
                                        FLAGS(SetPdepthSide{m,n}(j,1),colFLAGS{rowFLAGS(i)}(l),kMid)=1;
                                    end
                                    %                                     end
                                end
                            end
                        end


                        %%
                        app.FLAGS=FLAGS;
                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                        end
                        %%

                    case 'm'            % restore all the gates selected
                        % from all the decays of the set, if positive
                        %%
                        kMid=app.kMid;
                        kEnd=app.kEnd;

                        app.FindSelectedGates;

                        rowFLAGS=app.rowFLAGS;
                        colFLAGS=app.colFLAGS;
                        FLAGS=app.FLAGS;
                        MPOL=app.MPOL;
                        NGates=app.NGates;

                        %%
                        selectedNodes = app.Tree.SelectedNodes;
                        SetPdepthSide=app.SetPdepthSide;
                        [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                        hSet=height(SetPdepthSide{m,n});


                        for k=2:kMid
                            FLAGS(:,:,k-1)=FLAGS(:,:,k);
                        end
                        for j=1:hSet
                            for i=1:height(rowFLAGS)
                                for l=1:length(colFLAGS{rowFLAGS(i)})
                                    if ~isempty(colFLAGS{rowFLAGS(i)})
                                        if MPOL(SetPdepthSide{m,n}(j,1),19+NGates*2+colFLAGS{rowFLAGS(i)}(l))>0
                                            FLAGS(SetPdepthSide{m,n}(j,1),colFLAGS{rowFLAGS(i)}(l),kMid)=0;
                                        end
                                    end
                                end
                            end
                        end


                        %%
                        app.FLAGS=FLAGS;
                        app.PlotDecay;
                        app.Shown;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                        end
                        %%
                end

            elseif isequal(key,'space') % Show selected measurements in Decays and
                % delete current brush selection

                cla(app.UIAxes4)
                app.FindSelectedRow;
                app.Shown;
                app.PlotDecay;

                for l=1:length(app.UIAxes.Children)
                    app.UIAxes.Children(l).BrushData=0;
                end
                for l=1:length(app.UIAxes2.Children)
                    app.UIAxes2.Children(l).BrushData=0;
                end
                for l2=1:length(app.UIAxes3.Children)
                    app.UIAxes3.Children(l2).BrushData=0;
                end
                for l2=1:length(app.UIAxes4.Children)
                    if app.UIAxes4.Children(l2).Marker=='none'
                        app.UIAxes4.Children(l2).BrushData=0;
                    end
                end
                for l2=1:length(app.UIAxes3_2.Children)
                    if app.UIAxes3_2.Children(l2).Marker~='.'
                        app.UIAxes3_2.Children(l2).BrushData=0;
                    end
                end
            end
        end

        % Button pushed function: ColorbarEditorButton
        function ColorbarEditorButtonPushed(app, event)
            colormapeditor(app.UIAxes3);
        end

        % Button pushed function: SaveprocessingButton
        function SaveprocessingButtonPushed(app, event)
            FLAGS=app.FLAGS;
            TAB=app.TAB;
            MPOL=app.MPOL;
            RAWDATA=app.RAWDATA;
            QuiFile=app.QuiFile;
            NGates=app.NGates;

            %%Substitute flags in MPOL with the processed flags
            MPOL(:,17+NGates:16+NGates*2)=FLAGS(:,1:NGates,10);

            %%Reassembling .qui file
            FileID=fopen(QuiFile);
            filescan=textscan(FileID,'%s','delimiter','\n','whitespace',' ');

            row=zeros(1,1);
            row=strfind(filescan{1}, '%11');
            row=find(~cellfun('isempty',row),1);

            % %             Pathname=uigetdir;  %% For now, it overrites the same QuiFile
            FID=fopen(QuiFile,'w+');

            %1
            fprintf(FID,'%s\n','%1');
            fprintf(FID,'\n');

            %2
            fprintf(FID,'%s\n','%2');
            fprintf(FID,'\n');

            %3
            fprintf(FID,'%s\n','%3');
            fprintf(FID,'\n');

            %4
            Header4='%4 ElectrodeID ScalingID         UTMx         UTMy     Elevation         Depth ElFlag';
            Format4='   %11i %9i %12.4f %12.4f %13.4f %13.4f %6i\n';

            fprintf(FID,'%s\n',Header4');
            fprintf(FID,Format4,TAB{4}');
            fprintf(FID,'\n');

            %5
            fprintf(FID,'%s\n','%5');
            fprintf(FID,'\n');

            %6
            fprintf(FID,'%s\n','%6');
            fprintf(FID,'\n');

            %7
            Header7='%7 GateID  GateStart  GateWidth   GateTime  GateType';
            Format7='   %6i %10.3e %10.3e %10.3e %9i\n';

            fprintf(FID,'%s\n',Header7');
            fprintf(FID,Format7,TAB{7}');
            fprintf(FID,'\n');

            %8
            fprintf(FID,'%s\n','%8');
            fprintf(FID,'\n');

            %9
            fprintf(FID,'%s\n','%9');
            fprintf(FID,'\n');

            %10
            fprintf(FID,'%s\n','%10');
            fprintf(FID,'\n');

            %11
            %NGates=NGates+1;
            Header11='%11 NCurrWaves CW01ID CW01NTx CW01Tx1 CW01A1 CW01Tx2 CW01A2 RxM RxN     Xfocus     Yfocus     Pdepth VESID HESID GateSetID CW01STD01 DCSTD';
            for i=1:NGates-1
                Header11=[Header11,'  MSTD',num2str(i,'%2.2i')];
            end
            Header11=[Header11,'  DCFlag'];
            for i=1:NGates-1
                Header11=[Header11,'  MFlag',num2str(i,'%2.2i')];
            end
            Header11=[Header11,'        Rho        Key  CW01Val01         DC'];
            for i=1:NGates-1
                Header11=[Header11,'        M',num2str(i,'%2.2i')];
            end
            Header11=[Header11,'     Time  RawFile'];

            Format11=' %13i %6i %7i %7i %6i %7i %6i %3i %3i %10.3e %10.3e %10.3e %5i %5i %9i %9.2f %5.2f';

            for i=1:NGates-1
                Format11=[Format11,' %7.2f'];
            end
            Format11=[Format11,' %7i'];
            for i=1:NGates-1
                Format11=[Format11,' %8i'];
            end
            for i=1:NGates-1+4
                Format11=[Format11,' %10.3e'];
            end
            % Format11=[Format11,' %i8  %s\n'];
            Format11=[Format11,' %8i'];


            fprintf(FID,'%s\n',Header11');

            for i=1:height(MPOL)
                fprintf(FID,Format11,MPOL(i,:)');
                fprintf(FID,'  %s\n',string(RAWDATA(i))');
            end
            fclose(FID);

            saved=uiconfirm(app.UIFigure,'Saved', 'Saving processing','Icon','success','Options',{'OK'});


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');
%             if strcmpi(YN,'OK')
                Q=cell(1,1);
                importdata_processingapp(app.callingapp,Q,app.TAB);
                delete(app.settings_profileapp)
                delete(app.settings_pseudosecapp)
                delete(app.settings_stripeapp)
                delete(app.settings_decayapp)
                delete(app.settings_positionsapp)
                delete(app)
%             end
        end

        % Button pushed function: RawdataButton
        function RawdataButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProfileTab = uitab(app.TabGroup);
            app.ProfileTab.Title = 'Profile';
            app.Tree = uitree(app.ProfileTab, 'checkbox');
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.PositionPanel = uipanel(app.UIFigure);
            app.PositionPanel.Title = 'Position';
            app.UIAxes = uiaxes(app.PositionPanel);
            xlabel(app.UIAxes, 'Distance [m]')
            ylabel(app.UIAxes, 'Elevation [m]')
            zlabel(app.UIAxes, 'Z')
            app.SettingsButton_positions = uibutton(app.PositionPanel, 'push');
            app.SettingsButton_positions.ButtonPushedFcn = createCallbackFcn(app, @SettingsButton_positionsPushed, true);
            app.SettingsButton_positions.Icon = 'Settings_2.png';
            app.SettingsButton_positions.Text = '';
            app.DecaysPanel = uipanel(app.UIFigure);
            app.DecaysPanel.Title = 'Decays';
            app.UIAxes4 = uiaxes(app.DecaysPanel);
            xlabel(app.UIAxes4, 't [s]')
            ylabel(app.UIAxes4, 'M [mV/V]')
            zlabel(app.UIAxes4, 'Z')
            app.SettingsButton_decay = uibutton(app.DecaysPanel, 'push');
            app.SettingsButton_decay.ButtonPushedFcn = createCallbackFcn(app, @SettingsButton_decayPushed, true);
            app.SettingsButton_decay.Text = 'Settings';
            app.RawdataButton = uibutton(app.DecaysPanel, 'push');
            app.RawdataButton.ButtonPushedFcn = createCallbackFcn(app, @RawdataButtonPushed, true);
            app.RawdataButton.Visible = 'off';
            app.RawdataButton.Text = 'Raw data';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.DataprofileandPseudosectionTab = uitab(app.TabGroup2);
            app.DataprofileandPseudosectionTab.Title = 'Data profile and Pseudosection';
            app.Panel = uipanel(app.DataprofileandPseudosectionTab);
            app.UIAxes2 = uiaxes(app.Panel);
            xlabel(app.UIAxes2, 'Distance [m]')
            ylabel(app.UIAxes2, 'Rhoa [ohm m]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.Panel);
            xlabel(app.UIAxes3, 'Distance [m]')
            ylabel(app.UIAxes3, 'Elevation [m]')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.YDir = 'reverse';
            app.Panel_2 = uipanel(app.DataprofileandPseudosectionTab);
            app.CurveshiftfactorSpinnerLabel = uilabel(app.Panel_2);
            app.CurveshiftfactorSpinnerLabel.Text = 'Curve shift factor';
            app.CurveshiftfactorSpinner = uispinner(app.Panel_2);
            app.CurveshiftfactorSpinner.Step = 0.1;
            app.CurveshiftfactorSpinner.Limits = [1 2];
            app.CurveshiftfactorSpinner.ValueChangedFcn = createCallbackFcn(app, @CurveshiftfactorSpinnerValueChanged, true);
            app.CurveshiftfactorSpinner.Editable = 'off';
            app.CurveshiftfactorSpinner.Value = 1;
            app.ColorbarEditorButton = uibutton(app.Panel_2, 'push');
            app.ColorbarEditorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorbarEditorButtonPushed, true);
            app.ColorbarEditorButton.Text = 'Colorbar Editor';
            app.SettingsButton_profile = uibutton(app.Panel_2, 'push');
            app.SettingsButton_profile.ButtonPushedFcn = createCallbackFcn(app, @SettingsButton_profilePushed, true);
            app.SettingsButton_profile.Text = 'Settings Data Profile';
            app.SettingsButton_pseudosec = uibutton(app.Panel_2, 'push');
            app.SettingsButton_pseudosec.ButtonPushedFcn = createCallbackFcn(app, @SettingsButton_pseudosecPushed, true);
            app.SettingsButton_pseudosec.Text = 'Settings Pseudosection';
            app.DatastripeTab = uitab(app.TabGroup2);
            app.DatastripeTab.Title = 'Data stripe';
            app.Panel_4 = uipanel(app.DatastripeTab);
            app.Previous = uibutton(app.Panel_4, 'push');
            app.Previous.ButtonPushedFcn = createCallbackFcn(app, @PreviousPushed, true);
            app.Previous.Text = '<';
            app.Next = uibutton(app.Panel_4, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @NextPushed, true);
            app.Next.Text = '>';
            app.SettingsButton_stripe = uibutton(app.Panel_4, 'push');
            app.SettingsButton_stripe.ButtonPushedFcn = createCallbackFcn(app, @SettingsButton_stripePushed, true);
            app.SettingsButton_stripe.Text = 'Settings';
            app.Panel_5 = uipanel(app.DatastripeTab);
            app.UIAxes3_2 = uiaxes(app.Panel_5);
            xlabel(app.UIAxes3_2, 'Distance [m]')
            ylabel(app.UIAxes3_2, 'M [mV/V]')
            zlabel(app.UIAxes3_2, 'Z')
            app.HiderejectedpointsCheckBox = uicheckbox(app.UIFigure);
            app.HiderejectedpointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @HiderejectedpointsCheckBoxValueChanged, true);
            app.HiderejectedpointsCheckBox.Text = 'Hide rejected points';
            app.SaveprocessingButton = uibutton(app.UIFigure, 'push');
            app.SaveprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveprocessingButtonPushed, true);
            app.SaveprocessingButton.Icon = 'SaveProcessing.png';
            app.SaveprocessingButton.Text = 'Save processing ';
            app.ShowAllButton = uibutton(app.UIFigure, 'push');
            app.ShowAllButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAllButtonPushed, true);
            app.ShowAllButton.Text = 'Show All';
            app.HideAllButton = uibutton(app.UIFigure, 'push');
            app.HideAllButton.ButtonPushedFcn = createCallbackFcn(app, @HideAllButtonPushed, true);
            app.HideAllButton.Text = 'Hide All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = processingapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end