classdef settings_stripeapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CancelButton            matlab.ui.control.Button
        ApplyButton             matlab.ui.control.Button
        YAxisPanel              matlab.ui.container.Panel
        MaxEditField_2          matlab.ui.control.NumericEditField
        MaxEditField_2Label     matlab.ui.control.Label
        MinEditField_2          matlab.ui.control.NumericEditField
        MinEditField_2Label     matlab.ui.control.Label
        DefaultvaluesButton_2   matlab.ui.control.Button
        XAxisPanel              matlab.ui.container.Panel
        SetasdefaultCheckBox    matlab.ui.control.CheckBox
        IntervalEditField       matlab.ui.control.NumericEditField
        IntervalEditFieldLabel  matlab.ui.control.Label
        FixintervalCheckBox     matlab.ui.control.CheckBox
        MaxEditField            matlab.ui.control.NumericEditField
        MaxEditFieldLabel       matlab.ui.control.Label
        MinEditField            matlab.ui.control.NumericEditField
        MinEditFieldLabel       matlab.ui.control.Label
        DefaultvaluesButton     matlab.ui.control.Button
        OKButton                matlab.ui.control.Button
    end


    properties (Access = private)
        callingapp % Description
        settingsStripe
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, processingapp, settingsStripe)
            % qui aggiungere variabili in entrata da mainapp
            app.callingapp=processingapp;

            app.UIFigure.Name='Settings Data Stripe';

            % SET VALUES FROM PROFILEAPP IN SETTINGSAPP
            app.MinEditField.Value = settingsStripe{2}(1,1);    % X Min
            app.MaxEditField.Value = settingsStripe{2}(1,2);    % X Max
            app.MinEditField_2.Value = settingsStripe{2}(2,1);  % Y Min
            app.MaxEditField_2.Value = settingsStripe{2}(2,2);  % Y Max
            app.IntervalEditField.Value = settingsStripe{4}(1,1);


            app.settingsStripe=settingsStripe;
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            if app.FixintervalCheckBox.Value==1
                app.MaxEditField.Value=app.MinEditField.Value+app.IntervalEditField.Value;
            end
            if app.FixintervalCheckBox.Value==0
                app.IntervalEditField.Value=app.MaxEditField.Value-app.MinEditField.Value;
            end
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            if app.FixintervalCheckBox.Value==1
                app.MinEditField.Value=app.MaxEditField.Value-app.IntervalEditField.Value;
            end
            if app.FixintervalCheckBox.Value==0
                app.IntervalEditField.Value=app.MaxEditField.Value-app.MinEditField.Value;
            end
        end

        % Value changed function: IntervalEditField
        function IntervalEditFieldValueChanged(app, event)

            app.MaxEditField.Value=app.MinEditField.Value+app.IntervalEditField.Value;
        end

        % Value changed function: MinEditField_2
        function MinEditField_2ValueChanged(app, event)

        end

        % Value changed function: MaxEditField_2
        function MaxEditField_2ValueChanged(app, event)

        end

        % Value changed function: FixintervalCheckBox
        function FixintervalCheckBoxValueChanged(app, event)
%             if app.FixintervalCheckBox.Value==0
%                 app.MaxEditField.Editable='on';
%             end
        end

        % Button pushed function: DefaultvaluesButton
        function DefaultvaluesButtonPushed(app, event)
            settingsStripe=app.settingsStripe;
            app.MinEditField.Value = settingsStripe{1}(1,1);    % X Min
            app.MaxEditField.Value = settingsStripe{1}(1,2);    % X Max
            app.IntervalEditField.Value = settingsStripe{3}(1,1);
        end

        % Button pushed function: DefaultvaluesButton_2
        function DefaultvaluesButton_2Pushed(app, event)
            settingsStripe=app.settingsStripe;
            app.MinEditField_2.Value = settingsStripe{1}(2,1);  % Y Min
            app.MaxEditField_2.Value = settingsStripe{1}(2,2);  % Y Max
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % COLLECT THE OUTPUT TO SEND TO PROCESSINGAPP            
            settingsStripe=app.settingsStripe;

            settingsStripe{2}(1,1) = app.MinEditField.Value;
            settingsStripe{2}(1,2) = app.MaxEditField.Value;
            settingsStripe{2}(2,1) = app.MinEditField_2.Value;
            settingsStripe{2}(2,2) = app.MaxEditField_2.Value;
            settingsStripe{4}(1,1) = app.IntervalEditField.Value;

            importdata_settings_stripe(app.callingapp,settingsStripe)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.callingapp.SettingsButton_stripe.Enable='on'
            delete(app)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp.SettingsButton_stripe.Enable='on'
            delete(app)

        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % COLLECT THE OUTPUT TO SEND TO PROCESSINGAPP
            settingsStripe=app.settingsStripe;

            settingsStripe{2}(1,1) = app.MinEditField.Value;
            settingsStripe{2}(1,2) = app.MaxEditField.Value;
            settingsStripe{2}(2,1) = app.MinEditField_2.Value;
            settingsStripe{2}(2,2) = app.MaxEditField_2.Value;
            settingsStripe{4}(1,1) = app.IntervalEditField.Value;

            if app.SetasdefaultCheckBox.Value==1
                settingsStripe{3}=settingsStripe{4};
                settingsStripe{1}(1,2)=settingsStripe{1}(1,1)+settingsStripe{4};
            end

            importdata_settings_stripe(app.callingapp,settingsStripe)

            app.callingapp.SettingsButton_stripe.Enable='on'
            delete(app)
        end

        % Value changed function: SetasdefaultCheckBox
        function SetasdefaultCheckBoxValueChanged(app, event)
            value = app.SetasdefaultCheckBox.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.XAxisPanel = uipanel(app.UIFigure);
            app.XAxisPanel.Title = 'X Axis';
            app.DefaultvaluesButton = uibutton(app.XAxisPanel, 'push');
            app.DefaultvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultvaluesButtonPushed, true);
            app.DefaultvaluesButton.Text = 'Default values';
            app.MinEditFieldLabel = uilabel(app.XAxisPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MaxEditFieldLabel = uilabel(app.XAxisPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.FixintervalCheckBox = uicheckbox(app.XAxisPanel);
            app.FixintervalCheckBox.ValueChangedFcn = createCallbackFcn(app, @FixintervalCheckBoxValueChanged, true);
            app.FixintervalCheckBox.Enable = 'off';
            app.FixintervalCheckBox.Visible = 'off';
            app.FixintervalCheckBox.Text = 'Fix interval';
            app.FixintervalCheckBox.Value = true;
            app.IntervalEditFieldLabel = uilabel(app.XAxisPanel);
            app.IntervalEditFieldLabel.Text = 'Interval';
            app.IntervalEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.IntervalEditField.ValueChangedFcn = createCallbackFcn(app, @IntervalEditFieldValueChanged, true);
            app.SetasdefaultCheckBox = uicheckbox(app.XAxisPanel);
            app.SetasdefaultCheckBox.ValueChangedFcn = createCallbackFcn(app, @SetasdefaultCheckBoxValueChanged, true);
            app.SetasdefaultCheckBox.Text = 'Set as default';
            app.YAxisPanel = uipanel(app.UIFigure);
            app.YAxisPanel.Title = 'Y Axis';
            app.DefaultvaluesButton_2 = uibutton(app.YAxisPanel, 'push');
            app.DefaultvaluesButton_2.ButtonPushedFcn = createCallbackFcn(app, @DefaultvaluesButton_2Pushed, true);
            app.DefaultvaluesButton_2.Text = 'Default values';
            app.MinEditField_2Label = uilabel(app.YAxisPanel);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.YAxisPanel, 'numeric');
            app.MinEditField_2.ValueChangedFcn = createCallbackFcn(app, @MinEditField_2ValueChanged, true);
            app.MaxEditField_2Label = uilabel(app.YAxisPanel);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.YAxisPanel, 'numeric');
            app.MaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxEditField_2ValueChanged, true);
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = settings_stripeapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end